classdef EAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        buttonSettings           matlab.ui.control.Button
        startButton              matlab.ui.control.StateButton
        uipanel2                 matlab.ui.container.Panel
        pingTime                 matlab.ui.control.EditField
        text42                   matlab.ui.control.Label
        Depth                    matlab.ui.control.EditField
        lRange                   matlab.ui.control.EditField
        pInterval                matlab.ui.control.EditField
        text15                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        text8                    matlab.ui.control.Label
        uipanel1                 matlab.ui.container.Panel
        checkboxHake             matlab.ui.control.CheckBox
        checkboxKSync            matlab.ui.control.CheckBox
        checkboxNoise            matlab.ui.control.CheckBox
        bathyLoadStatus          matlab.ui.control.EditField
        loadBathy                matlab.ui.control.Button
        bathyFileBrowse          matlab.ui.control.Button
        bathyFile                matlab.ui.control.EditField
        text38                   matlab.ui.control.Label
        FBCorrected              matlab.ui.control.EditField
        fixFB                    matlab.ui.control.CheckBox
        nextBottomCheck          matlab.ui.control.EditField
        text48                   matlab.ui.control.Label
        buttonCheckBottomNow     matlab.ui.control.StateButton
        checkboxCheckDeepBottom  matlab.ui.control.CheckBox
        manualPIButton           matlab.ui.control.Button
        manualPI                 matlab.ui.control.EditField
        setManualPI              matlab.ui.control.CheckBox
        editDispRange            matlab.ui.control.CheckBox
        CenterPanel              matlab.ui.container.Panel
        StatusEditField          matlab.ui.control.EditField
        StatusEditFieldLabel     matlab.ui.control.Label
        axesDZH                  matlab.ui.control.UIAxes
        axesRoughness            matlab.ui.control.UIAxes
        axesSlope                matlab.ui.control.UIAxes
        axesDepth                matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        aliveTimer
        Audio   % Audio signal to be played when error occurs
        A       % Butterworth coefficient for lowpass filtering data
        B       % Butterworth coefficient for lowpass filtering data
        currentlyRunning
        currTime
        emittingDeepPing
        firstPing
        firstPingCount
        lastDepths
        lastDZHs
        lastRoughnesses
        lastSlopes
        param_url
        restartCount
        settings % Parameters from the Settings.txt file
        sub_url
        UDPObj
    end
    
    methods (Access = private)
        
        function readSettings(app)
            % Attempts to read a Settings.txt file various EAL parameters. If the files
            % exists, it loads and returns those parameters. If the file doesn't exist,
            % one is created with default parameters
            
            % If file doesn't exist, create it
            if exist(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'), 'file') ~= 2
                app.writeSettingsFile();
            end
            
            % Read text file
            data = fileread(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'));
            
            app.settings = regexp(data, ['IP Address =\s*(?<ER60IP>[^\s%]*).*' ...
                'Remote Port =\s*(?<RemotePort>[^\s%]*).*' ...
                'Name =\s*(?<ER60Name>[^\s%]*).*' ...
                'Password =\s*(?<ER60Password>[^\s%]*).*' ...
                '18 kHz =\s*(?<MaxLogRange18>[^\s%]*).*' ...
                '38 kHz =\s*(?<MaxLogRange38>[^\s%]*).*' ...
                '70 kHz =\s*(?<MaxLogRange70>[^\s%]*).*' ...
                '120 kHz =\s*(?<MaxLogRange120>[^\s%]*).*' ...
                '200 kHz =\s*(?<MaxLogRange200>[^\s%]*).*' ...
                '333 kHz =\s*(?<MaxLogRange333>[^\s%]*).*' ...
                'Processing buffer =\s*(?<ProcBuf>[^\s%]*).*' ...
                'Bottom Offset =\s*(?<BottomOffset>[^\s%]*).*' ...
                'Manual Ping Interval Override Time =\s*(?<ManPingTime>[^\s%]*).*' ...
                'External Depth Input Type =\s*(?<ExtDepthType>[^\s%]*).*' ...
                'External Depth Input IP =\s*(?<ExtDepthIP>[^\s%]*).*' ...
                'External Depth Input Port =\s*(?<ExtDepthPort>[^\s%]*).*' ...
                'External Depth Sound Speed =\s*(?<ExtDepthSV>[^\s%]*).*' ...
                'Detection Window Size =\s*(?<DetectionWindowSize>[^\s%]*).*' ...
                'Deep Bottom Ping Interval =\s*(?<DeepBotInt>[^\s%]*).*' ...
                'Deep Bottom Ping Range =\s*(?<DeepBotRange>[^\s%]*).*' ...
                'Passive Noise Interval =\s*(?<PassiveInt>[^\s%]*).*' ...
                'Number of passive pings =\s*(?<NumPassivePings>[^\s%]*).*' ...
                'Passive Noise Range =\s*(?<PassiveRange>[^\s%]*).*' ...
                'Removal Range =\s*(?<FBRemRange>[^\s%]*).*' ...
                '18 kHz Range =\s*(?<DispRange18>[^\s%]*).*' ...
                '38 kHz Range =\s*(?<DispRange38>[^\s%]*).*' ...
                '70 kHz Range =\s*(?<DispRange70>[^\s%]*).*' ...
                '120 kHz Range =\s*(?<DispRange120>[^\s%]*).*' ...
                '200 kHz Range =\s*(?<DispRange200>[^\s%]*).*' ...
                '333 kHz Range =\s*(?<DispRange333>[^\s%]*).*' ...
                'K-Sync IP =\s*(?<KSyncIP>[^\s%]*).*' ...
                'K-Sync UDP Port =\s*(?<KSyncUDPPort>[^\s%]*).*' ...
                'Non-ER60/EK80 Group Lengths =\s*(?<KSyncAdjust>[^\s%]*).*' ...
                ], 'names');
        end
        
        function writeSettingsFile(~)
            % If a Settings.txt file doesn't exist, create a template one
            % that the user can then modify

            % If programData folder doesn't exist, create it
            if ~isfolder('C:\ProgramData\EAL')
                mkdir('C:\ProgramData\EAL');
            end

            % Create file and get identifier
            fid = fopen(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'), 'w');
            
            % Write file
            fprintf(fid, '// ER60/EK80 Remoting Settings\r\n');
            fprintf(fid, 'IP Address = 192.168.123.105\t%% Local IP address defined in ER60/EK80 Remoting dialog\r\n');
            fprintf(fid, 'Remote Port = 37655\t%% Local Port defined in ER60 Remoting dialog\r\n');
            fprintf(fid, 'Name = Simrad\t%% Username defined in ER60/EK80 Users and Passwords dialog\r\n');
            fprintf(fid, 'Password =\t%% Password for the above username\r\n\r\n');
            
            fprintf(fid, '// Max Logging Range Settings\r\n');
            fprintf(fid, '18 kHz = 750\t%% Maximum range to log 18 kHz data (m)\r\n');
            fprintf(fid, '38 kHz = 750\t%% Maximum range to log 38 kHz data (m)\r\n');
            fprintf(fid, '70 kHz = 750\t%% Maximum range to log 70 kHz data (m)\r\n');
            fprintf(fid, '120 kHz = 750\t%% Maximum range to log 120 kHz data (m)\r\n');
            fprintf(fid, '200 kHz = 750\t%% Maximum range to log 200 kHz data (m)\r\n');
            fprintf(fid, '333 kHz = 750\t%% Maximum range to log 333 kHz data (m)\r\n\r\n');
            
            fprintf(fid, '// Ping Settings\r\n');
            fprintf(fid, 'Processing buffer = 0.16\t%% Time buffer to account for ER60 processing (s)\r\n');
            fprintf(fid, 'Bottom Offset = 60\t%% Logging range is bottom depth plus this offset (m)\r\n');
            fprintf(fid, 'Manual Ping Interval Override Time = 5\t%% Override time to use manual ping interval (min)\r\n\r\n');
            
            fprintf(fid, '// Bottom Detection Settings\r\n');
            fprintf(fid, 'External Depth Input Type = UDP\t%% Enter communication type of remote system supplying DBT or DBS inputs, either IP or UDP\r\n');
            fprintf(fid, 'External Depth Input IP = \t%% Enter IP of remote system supplying DBT or DBS inputs; blank if none\r\n');
            fprintf(fid, 'External Depth Input Port = \t%% Enter port of remote system supplying DBT or DBS inputs; blank if none\r\n');
            fprintf(fid, 'External Depth Sound Speed = 1500\t%% Sound speed of external system providing bottom depths (m/s) \r\n');
            fprintf(fid, 'Detection Window Size = 15\t%% Detection window is last depth +- this range (m)\r\n\r\n');
            
            fprintf(fid, '// Deep Bottom Ping Settings\r\n');
            fprintf(fid, 'Deep Bottom Ping Interval = 10\t%% Time between deep bottom detections (min)\r\n');
            fprintf(fid, 'Deep Bottom Ping Range = 5000\t%% Range (m) of deep bottom pings, if no bathymetry file\r\n\r\n');
            
            fprintf(fid, '// Noise Measurement Settings\r\n');
            fprintf(fid, 'Passive Noise Interval = 30\t%% Time between passive noise measurements (min)\r\n');
            fprintf(fid, 'Number of passive pings = 3\t%% Number of passive pings to collect for noise measurement\r\n');
            fprintf(fid, 'Passive Noise Range = 100\t%% Range (m) of passive noise pings\r\n\r\n');
            
            fprintf(fid, '// False Bottom Removal Settings\r\n');
            fprintf(fid, 'Removal Range = 250\t%% Range above which false bottoms will be removed (m)\r\n\r\n');
            
            fprintf(fid, '// Display Settings\r\n');
            fprintf(fid, '18 kHz Range = 750\t%% Display range of 18 kHz echogram (m)\r\n');
            fprintf(fid, '38 kHz Range = 750\t%% Display range of 38 kHz echogram (m)\r\n');
            fprintf(fid, '70 kHz Range = 750\t%% Display range of 70 kHz echogram (m)\r\n');
            fprintf(fid, '120 kHz Range = 750\t%% Display range of 120 kHz echogram (m)\r\n');
            fprintf(fid, '200 kHz Range = 750\t%% Display range of 200 kHz echogram (m)\r\n');
            fprintf(fid, '333 kHz Range = 750\t%% Display range of 333 kHz echogram (m)\r\n\r\n');
            
            fprintf(fid, '// K-Sync Settings\r\n');
            fprintf(fid, 'K-Sync IP = 157.237.60.169\t%% IP Address of K-Sync Synchronization Unit\r\n');
            fprintf(fid, 'K-Sync UDP Port = 4502\t%% UDP port to sending K-Sync data (typically not changed)\r\n');
            fprintf(fid, 'Non-ER60/EK80 Group Lengths = 0.02\t%% Time spent in groups not containing the ER60/EK80 (s)');
                        
            fclose(fid);
        end
        
        function results = dataReceivedFcn(app, src, event)
            
        end
        
        function connect2EK80(app)

            try

                %% Get/set EK80 connection settings
                app.EK80RequestID = 1;  % Init. EK80 request ID

                %% Request server info and open connection

                % Create UDP port object
                app.EK80 = udpport("datagram", "IPV4");

                % Send request server info
                write(app.EK80, ['RSI' char(0)], "char", ...
                    app.EK80IPEditField.Value, 37655);

                % Read header. If nothing is sent back, then a connection setting must
                % be wrong.
                try
                    % Read datagram response
                    data = read(app.EK80, 1);

                    % Parse header
                    header = char(data.Data(1:4));

                    if ~strcmp(header, ['SI2' char(0)])
                        error('Incorrect response.  Check IP address')
                    end
                catch ME
                    error('No response from EK80.  Check settings')
                end

                % Get remote commandport which should be used to set up subscriptions and
                commandPort = typecast(uint8(data.Data(265:268)), 'int32');  % Command Port

                %% Connect to EK80 server

                % Try to connect with a user and password which must be defined in
                % server EK80 application (Users and Passwords dialogue).
                write(app.EK80, ['CON' char(0) 'Name:Simrad;Password:' char(0)], 'char', ...
                    app.EK80IPEditField.Value, commandPort);

                % Read datagram response
                data = read(app.EK80, 1);

                % Parse header
                header = char(data.Data(1:4));

                % Receive response
                if strcmp(header, ['RES' char(0)])
                    response = char(data.Data(31:end));
                else
                    error('Unknown response: %s', header);
                end

                % Get CLIENTID
                tokens = regexp(response, 'ClientID:(\d+),', 'tokens');
                app.EK80CLIENTID = tokens{1}{1};

                % Initialiaze client sequence number
                app.EK80CLIENTSEQNO = 1;

            catch ME

                % Delete udp object to EK80 server
                if isfield(app, 'EK80')
                    if isvalid(app.EK80); delete(app.EK80); end
                end

                error(ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create UDP object for receiving subscription data
            app.UDPObj = udpport("datagram");

            % Link the callback using an anonymous function
            configureCallback(app.UDPObj, "datagram", 1, @(src, event) dataReceivedFcn(app, src, event));

            % Create timer to run function that keeps connection alive
            app.aliveTimer = timer("ExecutionMode","fixedRate", ...
                "Period",3, ...
                "BusyMode","queue", ...
                "TimerFcn",@(~,~) app.keepAlive());

            % Create audio signal for error alert
            WarnWave = [sin(1:.6:400), sin(1:.7:400), sin(1:.4:400)];
            app.Audio = audioplayer(WarnWave, 22050);
            
            % Get filter coefficients used for smoothing out Sv and Pr data for bottom
            % detection
            [app.B, app.A] = butter(2, .05);    % Lowpass filter coefficients
            
            %% Set figure axes properties
            
            % Remove the axis tick marks
            set(app.axesDepth, 'XTick', [], 'YTick', []);
            set(app.axesSlope, 'XTick', [], 'YTick', []);
            set(app.axesDZH, 'XTick', [], 'YTick', []);
            set(app.axesRoughness, 'XTick', [], 'YTick', []);
            
            % Create y-axis labels
            ylabel(app.axesDepth, 'Depth')
            ylabel(app.axesSlope, 'Slope')
            ylabel(app.axesDZH, 'h_{ADZ}')
            ylabel(app.axesRoughness, 'Roughness')
        end

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            value = app.startButton.Value;

            % If start button pressed
            if value
            
                %% Read settings values
                app.readSettings();

                % Create urls for calls to server
                app.param_url = ['http://' app.settings.ER60IP ':12345'];
                app.sub_url = ['http://' app.settings.ER60IP ':12346'];
            
                %% Create the various timer objects
            
                % % Timer for receiving subscription data
                % handles.subcriptionTimer = timer('ExecutionMode', 'fixedSpacing', ...
                %     'Period', 0.1, ...
                %     'BusyMode', 'drop', ...
                %     'Name', 'Receive data timer', ...
                %     'TimerFcn', @(obj, eventdata) getData(app));
            
                % % Timer for manual ping interval
                % app.setPITimer = timer('ExecutionMode', 'singleShot', ...
                %     'TimerFcn', @(obj, eventdata) stopManualPI(app), ...
                %     'Name', 'Manual Ping Interval Timer', ...
                %     'StartDelay', str2double(handles.settings.ManPingTime)*60);
            
                %% Initialize variables on each Start button press
                app.currTime = NaT;             % Holds current ping time
                app.firstPing = 1;              % Flag to signify EAL starting
                app.firstPingCount = 0;         % Init # of starting pings missed
                app.emittingDeepPing = 0;       % Flag for deep ping txmission
                app.restartCount = 0;           % Init # of restarts
                app.currentlyRunning = 0;       % Tells if getData() is running
            
                % Initialize plotting variables
                app.lastDepths = nan(100,1);
                app.lastSlopes = nan(100,1);
                app.lastDZHs = nan(100,1);
                app.lastRoughnesses = nan(100,1);
            
                %% Change GUI properties
            
                app.StatusEditField.Value = '';
            
                % Disable false bottom file selection and load button while running
                set(app.bathyFile, 'Enable', 'off');
                set(app.bathyFileBrowse, 'Enable', 'off');
                set(app.loadBathy, 'Enable', 'off');
            
                set(app.setManualPI, 'Enable', 'on');   % Enable Set Ping Interval
            
                % If bathymetry estimator doesn't exist, then disable all false
                % bottom removal inputs.
                if ~isfield(app, 'estFunc')
                    app.bathyLoadStatus.Value = 'No bathymetry data loaded!';
                    % set(app.bathyLoadStatus, 'String', 'No bathymetry data loaded!');
                    set(app.bathyLoadStatus, 'Enable', 'off');
                end
            
                % Change Start button and disable inputs
                set(app.startButton, 'BackgroundColor', 'r')	% Make button red
                % set(app.startButton, 'String', 'Stop')          % Change to Stop
                app.startButton.Text = 'Stop';
            
                % Disable K-Sync inputs while operating
                set(app.checkboxKSync, 'Enable', 'Off')
                        
                % Make all output parameters blank
                % set(app.Depth, 'String', '')
                % set(app.pInterval, 'String', '')
                % set(app.lRange, 'String', '')
                app.Depth.Value = '';
                app.pInterval.Value = '';
                app.lRange.Value = '';
            
            %     % Update GUI display
            %     drawnow nocallbacks
            % %     drawnow expose update
            
            %% Get list of installed transceivers
            
            url = [app.param_url '/api/sounder/ping-configuration/channel-list'];
            options = weboptions('ContentType', 'json');
            try
                channelIDs = webread(url, options);
                disp('Installed transceivers:');
                disp(channelIDs);
            catch ME
                disp('Error:');
                disp(ME.message);
                disp('Check if EK80 is running and port 12345 is correct.');
            end

                %% Try connecting to ER60/EK80
                try
                    connect2EK80(app);
                catch ME
                    set(handles.restartTest, 'String', ME.message);
                    enableInputs(app, handles);
                    return
                end
            
                %% If using K-Sync, create udp object for sending depth outputs
                if get(handles.checkboxKSync, 'Value')
                    handles.KSyncDepth = udpport("byte", "IPV4");
                end
            
                %% If External Depth sensor info provided, create tcpip object
            
                % If External Depth sensor parameters are defined in the Settings.txt
                % file, create a TCP/IP object
                if ~isempty(handles.settings.ExtDepthIP)
                    if strcmp(handles.settings.ExtDepthType, 'IP')
                        handles.ExtDepth = tcpclient(handles.settings.ExtDepthIP, str2double(handles.settings.ExtDepthPort));
                    elseif strcmp(handles.settings.ExtDepthType, 'UDP')
                        handles.ExtDepth = udpport("datagram", "IPV4", 'LocalPort', str2double(handles.settings.ExtDepthPort));
%                         handles.ExtDepth.RemotePort = str2double(handles.settings.ExtDepthPort);
                        % configureMulticast(handles.ExtDepth, handles.settings.ExtDepthIP);
                    end
                end
                        
                %% Create file for storing noise floor measurements
            
                % If Noise Measurement directory doesn't exist, create it
                if ~isfolder(fullfile('C:\ProgramData\EAL', 'Noise Measurements'))
                    mkdir(fullfile('C:\ProgramData\EAL', 'Noise Measurements'))
                end
            
                % Create noise file
                handles.noiseFile = fopen(fullfile('C:\ProgramData\EAL', 'Noise Measurements', ...
                    sprintf('passiveNoise_D%s.txt', char(datetime('now', Format='yyyyMMdd''T''HHmmss')))), 'w');
            
                % Append header to file
                str = '';
                for i = 1:length(handles.freqs)
                    str = sprintf('%s,%dkHz(dB)', str, handles.freqs(i)/1e3);
                end
                fprintf(handles.noiseFile, 'Date,Time%s\n', str);
            
                %% Initialize plotting variables for rolling calculations
                handles.rollingX = cell(length(handles.freqs), 3);
                handles.rollingY = cell(length(handles.freqs), 3);
                handles.rollingZ = cell(length(handles.freqs), 3);
            
                %% Change display range
            
                % Set the logging range to the ranges defined in Settings.txt
                handles.normRange = nan(length(handles.freqs), 1);
                for i = 1:length(handles.freqs)
                    handles.normRange(i) = str2double(eval(sprintf('handles.settings.MaxLogRange%d', handles.freqs(i)/1e3)));
                end
            
                % Set the variable that will hold the current logging range
                handles.currRange = handles.normRange;
            
                % Put that range in the GUI
                set(handles.lRange, 'String', 'NA')
            
                % Initialize the first detection window using the full logging range.
                % Because there can be multiple logging ranges, use the maximum range
                % for the window.
                handles.RangeWindow = [0; max(handles.normRange)];
            
                % Change echogram display range
                for i = 1:length(handles.freqs)
                    rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/RangeStart</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' int2str(handles.normRange(i)) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    handles = sendrequest(app, handles, rString1);
                    handles = sendrequest(app, handles, rString2);
                end
            
                %% Get ER60 parameters
            
                % Get sound speed for calculations
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{1} '/SoundVelocity</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                handles.c = str2double(readbetween(app, '<value dt="5">','</value>',response));   % in ms
            
                % Get frequency dependent parameters that will be used for processing
                for i = 1:length(handles.freqs)
            
                    % Alongship sensitivity (degrees)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AngleSensitivityAlongship</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.AlongshipAngleSensitivity(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Athwartship sensitivity (degrees)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AngleSensitivityAthwartship</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.AthwartshipAngleSensitivity(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Pulse length
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/PulseLength</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.tau(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Absorption
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AbsorptionCoefficient</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.alpha(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Transmit Power
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/TransmitPower</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.Pt(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % On-axis Gain
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/Gain</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.G0(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Equivalent Two-Way Beam Angle
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/EquivalentBeamAngle</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.psi(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % S_A Correction
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/SaCorrection</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.Sa_corr(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
                end
                        
                %% Store ER60 settings
            
                % Calculate ping interval based on logging range
                procTime = str2double(handles.settings.ProcBuf);
                handles.normRate = round(100*(procTime + (max(handles.normRange) * 2/handles.c)))/100;
            
                % Put those values in the GUI
                set(handles.pInterval, 'String', num2str(handles.normRate))
                set(handles.manualPI, 'String', num2str(handles.normRate))
            
                % See if ER60 was recording
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/SaveRawData</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                handles.isRec = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                %% Collect passive noise measurements, if wanted
            
                % Make passive noise measurements if checkbox is selected
                if get(handles.checkboxNoise, 'Value')
            
                    % If noise timer exists, check to see if it's time for a new
                    % measurements
                    if isfield(handles, 'noiseTimer')
            
                        % If time for a new measurements, do it
                        if toc(handles.noiseTimer) > 60*str2double(handles.settings.PassiveInt)
                            handles = collectNoise(app, handles);
            
                        % Otherwise, display when the next measurement occurs
                        else
                            fprintf('Next noise measurements in %.0f seconds\n', 60*str2double(handles.settings.PassiveInt) - toc(handles.noiseTimer))
                        end
            
                    % If the timer doesn't exist, then collect noise data
                    else
                        handles = collectNoise(app, handles);
                    end
            
                % If user doesn't wish to collect noise measurements, set to NaN
                else
                    handles.noiseFloor = nan(1, length(handles.freqs));
                end
            
                %% Subscribe to data for all ER60 frequencies
                try
                    handles = subscribe2ER60(app, handles);
                catch ME
                    set(handles.restartTest, 'String', ME.message);
                    enableInputs(app, handles);
                    return
                end
            
                %% Create plots
                handles.h(1) = plot(handles.axesDepth, handles.lastDepths, 'LineWidth', 3);
                set(handles.axesDepth, 'YDir', 'reverse', 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesDepth, 'Depth')
            
                handles.h(2) = plot(handles.axesSlope, handles.lastSlopes, 'LineWidth', 3);
                set(handles.axesSlope, 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesSlope, 'Slope')
            
                handles.h(3) = plot(handles.axesDZH, handles.lastDZHs, 'LineWidth', 3);
                set(handles.axesDZH, 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesDZH, 'h_{ADZ}')
            
                handles.h(4) = plot(handles.axesRoughness, handles.lastRoughnesses, 'LineWidth', 3);
                set(handles.axesRoughness, 'XGrid', 'on', 'YGrid', 'on')
                ylabel(handles.axesRoughness, 'Roughness')
            
                %% Update handles and start receiving data
            
                % The handles structure must be updated first so that when the
                % subscription timer is started, it is accessing the most up-to-date
                % version of handles.
                guidata(handles.output, handles)	% Update GUI handles structure
                start(handles.subcriptionTimer);           % Start reading ER60 udp packets
            end
        end

        % Button pushed function: buttonSettings
        function buttonSettingsButtonPushed(app, event)

            % If Settings.txt doesn't exist, create it
            if exist(fullfile('C:\ProgramData\EAL', 'EAL_Settings.txt'), 'file') ~= 2
                app.writeSettingsFile;
            end
            
            % Open Settings file in Notepad
            system('start "" "C:\ProgramData\EAL\EAL_Settings.txt"');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {734, 734, 734};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {734, 734};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {375, '1x', 110};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.uipanel1 = uipanel(app.LeftPanel);
            app.uipanel1.Title = 'Input';
            app.uipanel1.Tag = 'uipanel1';
            app.editDispRange = uicheckbox(app.uipanel1);
            app.editDispRange.Tag = 'editDispRange';
            app.editDispRange.Text = 'Unlock display range?';
            app.setManualPI = uicheckbox(app.uipanel1);
            app.setManualPI.Tag = 'setManualPI';
            app.setManualPI.Enable = 'off';
            app.setManualPI.Text = 'Set Ping Interval';
            app.manualPI = uieditfield(app.uipanel1, 'text');
            app.manualPI.Tag = 'manualPI';
            app.manualPI.Enable = 'off';
            app.manualPIButton = uibutton(app.uipanel1, 'push');
            app.manualPIButton.Tag = 'manualPIButton';
            app.manualPIButton.Enable = 'off';
            app.manualPIButton.Text = 'Set';
            app.checkboxCheckDeepBottom = uicheckbox(app.uipanel1);
            app.checkboxCheckDeepBottom.Tag = 'checkboxCheckDeepBottom';
            app.checkboxCheckDeepBottom.Text = 'Check Deep Bottom?';
            app.buttonCheckBottomNow = uibutton(app.uipanel1, 'state');
            app.buttonCheckBottomNow.Tag = 'buttonCheckBottomNow';
            app.buttonCheckBottomNow.Text = 'Check now';
            app.text48 = uilabel(app.uipanel1);
            app.text48.Tag = 'text48';
            app.text48.Text = 'Next detection:';
            app.nextBottomCheck = uieditfield(app.uipanel1, 'text');
            app.nextBottomCheck.Tag = 'nextBottomCheck';
            app.nextBottomCheck.Enable = 'off';
            app.fixFB = uicheckbox(app.uipanel1);
            app.fixFB.Tag = 'fixFB';
            app.fixFB.Text = 'Correct false bottom?';
            app.fixFB.Value = true;
            app.FBCorrected = uieditfield(app.uipanel1, 'text');
            app.FBCorrected.Tag = 'FBCorrected';
            app.FBCorrected.Enable = 'off';
            app.text38 = uilabel(app.uipanel1);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Bathymetry file:';
            app.bathyFile = uieditfield(app.uipanel1, 'text');
            app.bathyFile.Tag = 'bathyFile';
            app.bathyFileBrowse = uibutton(app.uipanel1, 'push');
            app.bathyFileBrowse.Tag = 'bathyFileBrowse';
            app.bathyFileBrowse.Text = '...';
            app.loadBathy = uibutton(app.uipanel1, 'push');
            app.loadBathy.Tag = 'loadBathy';
            app.loadBathy.Text = 'Load';
            app.bathyLoadStatus = uieditfield(app.uipanel1, 'text');
            app.bathyLoadStatus.Tag = 'bathyLoadStatus';
            app.bathyLoadStatus.Enable = 'off';
            app.bathyLoadStatus.Value = 'No bathymetry data loaded!';
            app.checkboxNoise = uicheckbox(app.uipanel1);
            app.checkboxNoise.Tag = 'checkboxNoise';
            app.checkboxNoise.Text = 'Measure noise?';
            app.checkboxKSync = uicheckbox(app.uipanel1);
            app.checkboxKSync.Tag = 'checkboxKSync';
            app.checkboxKSync.Text = 'K-Sync?';
            app.checkboxKSync.Value = true;
            app.checkboxHake = uicheckbox(app.uipanel1);
            app.checkboxHake.Tag = 'checkboxHake';
            app.checkboxHake.Text = 'Hake?';
            app.uipanel2 = uipanel(app.LeftPanel);
            app.uipanel2.Title = 'Output';
            app.uipanel2.Tag = 'uipanel2';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Depth (m):';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Ping Interval (s):';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Logging Depth (m):';
            app.pInterval = uieditfield(app.uipanel2, 'text');
            app.pInterval.Tag = 'pInterval';
            app.pInterval.Enable = 'off';
            app.lRange = uieditfield(app.uipanel2, 'text');
            app.lRange.Tag = 'lRange';
            app.lRange.Enable = 'off';
            app.Depth = uieditfield(app.uipanel2, 'text');
            app.Depth.Tag = 'Depth';
            app.Depth.Enable = 'off';
            app.text42 = uilabel(app.uipanel2);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Ping Time:';
            app.pingTime = uieditfield(app.uipanel2, 'text');
            app.pingTime.Tag = 'pingTime';
            app.pingTime.Enable = 'off';
            app.startButton = uibutton(app.LeftPanel, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Tag = 'startButton';
            app.startButton.Text = 'START';
            app.buttonSettings = uibutton(app.LeftPanel, 'push');
            app.buttonSettings.ButtonPushedFcn = createCallbackFcn(app, @buttonSettingsButtonPushed, true);
            app.buttonSettings.Tag = 'buttonSettings';
            app.buttonSettings.Text = 'Open Settings';
            app.CenterPanel = uipanel(app.GridLayout);
            app.axesDepth = uiaxes(app.CenterPanel);
            app.axesDepth.Tag = 'axesDepth';
            app.axesSlope = uiaxes(app.CenterPanel);
            app.axesSlope.Tag = 'axesSlope';
            app.axesRoughness = uiaxes(app.CenterPanel);
            app.axesRoughness.Tag = 'axesRoughness';
            app.axesDZH = uiaxes(app.CenterPanel);
            app.axesDZH.Tag = 'axesDZH';
            app.StatusEditFieldLabel = uilabel(app.CenterPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.CenterPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end