classdef EAL_IWCPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        buttonSettings           matlab.ui.control.Button
        restartTest              matlab.ui.control.EditField
        startButton              matlab.ui.control.StateButton
        uipanel2                 matlab.ui.container.Panel
        pingTime                 matlab.ui.control.EditField
        text42                   matlab.ui.control.Label
        Depth                    matlab.ui.control.EditField
        lRange                   matlab.ui.control.EditField
        pInterval                matlab.ui.control.EditField
        text15                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        text8                    matlab.ui.control.Label
        uipanel1                 matlab.ui.container.Panel
        checkboxHake             matlab.ui.control.CheckBox
        checkboxKSync            matlab.ui.control.CheckBox
        checkboxNoise            matlab.ui.control.CheckBox
        bathyLoadStatus          matlab.ui.control.EditField
        loadBathy                matlab.ui.control.Button
        bathyFileBrowse          matlab.ui.control.Button
        bathyFile                matlab.ui.control.EditField
        text38                   matlab.ui.control.Label
        FBCorrected              matlab.ui.control.EditField
        fixFB                    matlab.ui.control.CheckBox
        nextBottomCheck          matlab.ui.control.EditField
        text48                   matlab.ui.control.Label
        buttonCheckBottomNow     matlab.ui.control.StateButton
        checkboxCheckDeepBottom  matlab.ui.control.CheckBox
        manualPIButton           matlab.ui.control.Button
        manualPI                 matlab.ui.control.EditField
        setManualPI              matlab.ui.control.CheckBox
        editDispRange            matlab.ui.control.CheckBox
        axesDZH                  matlab.ui.control.UIAxes
        axesRoughness            matlab.ui.control.UIAxes
        axesSlope                matlab.ui.control.UIAxes
        axesDepth                matlab.ui.control.UIAxes
    end

    
    methods(Static)

        function writeSettingsFile
            
            % If programData folder doesn't exist, create it
            if ~isfolder('C:\ProgramData\EAL')
                mkdir('C:\ProgramData\EAL');
            end

            % Create file and get identifier
            fid = fopen(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'), 'w');
            
            % Write file
            fprintf(fid, '// ER60/EK80 Remoting Settings\r\n');
            fprintf(fid, 'IP Address = 192.168.123.105\t%% Local IP address defined in ER60/EK80 Remoting dialog\r\n');
            fprintf(fid, 'Remote Port = 37655\t%% Local Port defined in ER60 Remoting dialog\r\n');
            fprintf(fid, 'Name = Simrad\t%% Username defined in ER60/EK80 Users and Passwords dialog\r\n');
            fprintf(fid, 'Password =\t%% Password for the above username\r\n\r\n');
            
            fprintf(fid, '// Max Logging Range Settings\r\n');
            fprintf(fid, '18 kHz = 750\t%% Maximum range to log 18 kHz data (m)\r\n');
            fprintf(fid, '38 kHz = 750\t%% Maximum range to log 38 kHz data (m)\r\n');
            fprintf(fid, '70 kHz = 750\t%% Maximum range to log 70 kHz data (m)\r\n');
            fprintf(fid, '120 kHz = 750\t%% Maximum range to log 120 kHz data (m)\r\n');
            fprintf(fid, '200 kHz = 750\t%% Maximum range to log 200 kHz data (m)\r\n');
            fprintf(fid, '333 kHz = 750\t%% Maximum range to log 333 kHz data (m)\r\n\r\n');
            
            fprintf(fid, '// Ping Settings\r\n');
            fprintf(fid, 'Processing buffer = 0.16\t%% Time buffer to account for ER60 processing (s)\r\n');
            fprintf(fid, 'Bottom Offset = 60\t%% Logging range is bottom depth plus this offset (m)\r\n');
            fprintf(fid, 'Manual Ping Interval Override Time = 5\t%% Override time to use manual ping interval (min)\r\n\r\n');
            
            fprintf(fid, '// Bottom Detection Settings\r\n');
            fprintf(fid, 'External Depth Input Type = UDP\t%% Enter communication type of remote system supplying DBT or DBS inputs, either IP or UDP\r\n');
            fprintf(fid, 'External Depth Input IP = \t%% Enter IP of remote system supplying DBT or DBS inputs; blank if none\r\n');
            fprintf(fid, 'External Depth Input Port = \t%% Enter port of remote system supplying DBT or DBS inputs; blank if none\r\n');
            fprintf(fid, 'External Depth Sound Speed = 1500\t%% Sound speed of external system providing bottom depths (m/s) \r\n');
            fprintf(fid, 'Detection Window Size = 15\t%% Detection window is last depth +- this range (m)\r\n\r\n');
            
            fprintf(fid, '// Deep Bottom Ping Settings\r\n');
            fprintf(fid, 'Deep Bottom Ping Interval = 10\t%% Time between deep bottom detections (min)\r\n');
            fprintf(fid, 'Deep Bottom Ping Range = 5000\t%% Range (m) of deep bottom pings, if no bathymetry file\r\n\r\n');
            
            fprintf(fid, '// Noise Measurement Settings\r\n');
            fprintf(fid, 'Passive Noise Interval = 30\t%% Time between passive noise measurements (min)\r\n');
            fprintf(fid, 'Number of passive pings = 3\t%% Number of passive pings to collect for noise measurement\r\n');
            fprintf(fid, 'Passive Noise Range = 100\t%% Range (m) of passive noise pings\r\n\r\n');
            
            fprintf(fid, '// False Bottom Removal Settings\r\n');
            fprintf(fid, 'Removal Range = 250\t%% Range above which false bottoms will be removed (m)\r\n\r\n');
            
            fprintf(fid, '// Display Settings\r\n');
            fprintf(fid, '18 kHz Range = 750\t%% Display range of 18 kHz echogram (m)\r\n');
            fprintf(fid, '38 kHz Range = 750\t%% Display range of 38 kHz echogram (m)\r\n');
            fprintf(fid, '70 kHz Range = 750\t%% Display range of 70 kHz echogram (m)\r\n');
            fprintf(fid, '120 kHz Range = 750\t%% Display range of 120 kHz echogram (m)\r\n');
            fprintf(fid, '200 kHz Range = 750\t%% Display range of 200 kHz echogram (m)\r\n');
            fprintf(fid, '333 kHz Range = 750\t%% Display range of 333 kHz echogram (m)\r\n\r\n');
            
            fprintf(fid, '// K-Sync Settings\r\n');
            fprintf(fid, 'K-Sync IP = 157.237.60.169\t%% IP Address of K-Sync Synchronization Unit\r\n');
            fprintf(fid, 'K-Sync UDP Port = 4502\t%% UDP port to sending K-Sync data (typically not changed)\r\n');
            fprintf(fid, 'Non-ER60/EK80 Group Lengths = 0.02\t%% Time spent in groups not containing the ER60/EK80 (s)');
                        
            fclose(fid);
        end
    end

    methods (Access = private)
        function IPAddress_CreateFcn(app, hObject, ~, ~) %#ok<*INUSD> 
            % hObject    handle to IPAddress (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function KSyncIP_CreateFcn(app, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to KSyncIP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
                
        function handles = collectNoise(app, handles)
            
            fprintf('Preparing for passive noise measurements\n');
            
            handles.noiseTimer = tic;   % Restart noise collection timer
            
            % Obtain individual channel flag
            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/IndividualChannelRecordingRange</paramName><time>0</time></GetParameter></method></request>' char(0)];
            [handles, response] = sendrequest(app, handles, rString);
            IndChannelFlag = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
            % Initialize variable to hold old logging ranges if using individual
            % logging ranges
            if IndChannelFlag
                oldLoggingRange = cell(length(handles.freqs), 1);
            end
            
            % For each frequency, store current ping mode (i.e. active or passive) then
            % put into passive mode
            fprintf('Putting each transceiver in passive mode\n')
            currMode = cell(length(handles.freqs), 1);
            for i = 1:length(handles.freqs)
            
                % Get current mode
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/ChannelMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                currMode{i} = readbetween(app, '<value dt="3">','</value>',response);
            
                % Put into passive mode
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/ChannelMode</paramName><paramValue>1</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString);
            
                % If using individual logging ranges, store
                if IndChannelFlag
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/' handles.ER60transceiverID{i} '_ES/Range</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    oldLoggingRange{i} = readbetween(app, '<value dt="3">','</value>',response);
            
                % Otherwise, get and store the common logging range
                elseif i == 1
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/SampleRange</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    oldLoggingRange = readbetween(app, '<value dt="3">','</value>',response);
                end
            end
            
            % Calculate ping interval using logging range of passive data
            PI = str2double(handles.settings.ProcBuf) + (str2double(handles.settings.PassiveRange) * 2/handles.c);
            
            % If using with K-Sync, send depth value for the new range
            if get(handles.checkboxKSync, 'Value')
                depthM = PI * 1500/2;
                NMEA = sprintf('$SDDPT,%.2f,0.00,*', depthM);
            
                % Calculate checksum
                temp = double(NMEA(2:end-1));
                checksum = 0;
                for j = 1:length(temp)
                    checksum = bitxor(checksum, temp(j));
                    checksum = uint16(checksum);
                end
                checksum = double(checksum);
                checksum = dec2hex(checksum);
                if length(checksum) == 1; checksum = strcat('0',checksum); end
            
                % Send depth to K-Sync 5 times every 1/2 second
                % fopen(handles.KSyncDepth);
                for i = 1:5
                    writeline(handles.KSyncDepth, ...
                        sprintf('%s%s%s', NMEA, checksum, [char(13) newline]), ...
                        handles.settings.KSyncIP, str2double(handles.settings.KSyncUDPPort))
                    % fprintf(handles.KSyncDepth, sprintf('%s%s%s', NMEA, checksum, [char(13) newline]));
                    java.lang.Thread.sleep(500);
                end
                % fclose(handles.KSyncDepth);
            end
            
            % If using EK80 with individual logging ranges, then need to set sample
            % ranges separately
            if IndChannelFlag
                for i = 1:length(handles.freqs)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/' handles.ER60transceiverID{i} '_ES/Range</paramName><paramValue>' handles.settings.PassiveRange '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                    handles = sendrequest(app, handles, rString);
                end
            
            % Else, set the common logging range
            else
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/SampleRange</paramName><paramValue>' handles.settings.PassiveRange '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString);
            end
            
            % Cycle through each GPT/WBT
            detectionRanges = cell(length(handles.freqs), 1);
            displayRanges = cell(length(handles.freqs), 1);
            for i = 1:length(handles.freqs)
            
                % Get and then set bottom detection and display ranges for EK80
                rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/LowerDetectorLimit</paramName><time>0</time></GetParameter></method></request>' char(0)];
                rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><time>0</time></GetParameter></method></request>' char(0)];
                rString3 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/UpperDetectorLimit</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                rString4 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/LowerDetectorLimit</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                rString5 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' handles.settings.PassiveRange '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
            
                % Get detection ranges
                [handles, response] = sendrequest(app, handles, rString1);
                detectionRanges{i} = readbetween(app, '<value dt="5">','</value>',response);
            
                % Get display ranges
                [handles, response] = sendrequest(app, handles, rString2);
                displayRanges{i} = readbetween(app, '<value dt="5">','</value>',response);
            
                % Set detection and display ranges
                handles = sendrequest(app, handles, rString3);
                handles = sendrequest(app, handles, rString4);
                handles = sendrequest(app, handles, rString5);
            end
            
            % Monitor ER60 noise estimates from one frequency for at least one change,
            % to ensure capacitors discharge
            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{1} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
            [handles, response] = sendrequest(app, handles, rString);
            oldEst = str2double(readbetween(app, '<value dt="5">','</value>',response));
            while 1
            
                % Send alive messages to ER60 and EK80 objects
                handles = sendAliveMessage(app, handles);
            
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{1} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                newEst = str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                if ~isequal(newEst, oldEst)
                    oldEst = newEst;
                    break
                end
            end
            
            % Collect passive measurements
            numPings = str2double(handles.settings.NumPassivePings);
            noise = nan(numPings,length(handles.freqs));
            for i = 1:numPings
            
                % Continually read noise estimate from single frequency until it
                % changes from last reading
                while 1
            
                    % Send alive messages to ER60 and EK80 objects
                    handles = sendAliveMessage(app, handles);
            
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{1} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    newEst = str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    if ~isequal(newEst, oldEst)
                        oldEst = newEst;
                        break
                    end
                end
            
                % Get the ER60 detected noise estimates for each frequency
                for j = 1:length(handles.freqs)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{j} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    noise(i,j) = str2double(readbetween(app, '<value dt="5">','</value>',response));
                end
            end
            
            % Store the ER60 median noise
            handles.noiseFloor = median(noise, 1);
            disp(handles.noiseFloor);
            
            % Update ER60 Noise file
            fprintf(handles.noiseFile, ...
                ['%s' repmat(',%.2f', 1, length(handles.noiseFloor)) '\n'], ...
                datetime('now', Format='ddMMMyyyy,HHmmss'), ...
                handles.noiseFloor(1:end-1), handles.noiseFloor(end));
            
            % Put each ER60 frequency back into the mode that it was in before making
            % passive measurements. That is, not every frequency should be placed in
            % active mode if it wasn't already active
            fprintf('Putting transceivers in active mode\n')
            for i = 1:length(handles.freqs)
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/ChannelMode</paramName><paramValue>' currMode{i} '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString);
            end
            
            % Read noise estimates to determine when a ping has elapsed
            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{1} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
            [handles, response] = sendrequest(app, handles, rString);
            oldEst = str2double(readbetween(app, '<value dt="5">','</value>',response));
            while 1
            
                % Send alive messages to ER60 and EK80 objects
                handles = sendAliveMessage(app, handles);
            
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{1} '_ES/ChannelProcessingCommon/NoiseEstimate</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                newEst = str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                if ~isequal(newEst, oldEst)
                    break
                end
            end
            
            fprintf('Changing logging range back to what it was\n')
            
            % If using EK80 with individual logging ranges, then need to set sample
            % ranges separately
            if IndChannelFlag
                for i = 1:length(handles.freqs)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/' handles.ER60transceiverID{i} '_ES/Range</paramName><paramValue>' oldLoggingRange{i} '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                    handles = sendrequest(app, handles, rString);
                end
            else
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/SampleRange</paramName><paramValue>' oldLoggingRange '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString);
            end
            
            % Change display ranges and ER60 bottom detections back
            for i = 1:length(handles.freqs)
            
                % Change bottom detection and display ranges
                rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/UpperDetectorLimit</paramName><paramValue>30</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/LowerDetectorLimit</paramName><paramValue>' detectionRanges{i} '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                rString3 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' displayRanges{i} '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString1);
                handles = sendrequest(app, handles, rString2);
                handles = sendrequest(app, handles, rString3);
            end
            
            % If using with K-Sync, send depth value for the new range
            PI = str2double(get(handles.pInterval, 'String'));
            if get(handles.checkboxKSync, 'Value')
                depthM = PI * 1500/2;
                NMEA = sprintf('$SDDPT,%.2f,0.00,*', depthM);
            
                % Calculate checksum
                temp = double(NMEA(2:end-1));
                checksum = 0;
                for j = 1:length(temp)
                    checksum = bitxor(checksum, temp(j));
                    checksum = uint16(checksum);
                end
                checksum = double(checksum);
                checksum = dec2hex(checksum);
                if length(checksum) == 1; checksum = strcat('0',checksum); end
            
                % Send depth to K-Sync 5 times every 1/2 second
                % fopen(handles.KSyncDepth);
                for i = 1:5
                    writeline(handles.KSyncDepth, ...
                        sprintf('%s%s%s', NMEA, checksum, [char(13) newline]), ...
                        handles.settings.KSyncIP, str2double(handles.settings.KSyncUDPPort))
                    % fprintf(handles.KSyncDepth, sprintf('%s%s%s', NMEA, checksum, [char(13) newline]));
            %         pause(0.5)
                    java.lang.Thread.sleep(500);
                end
                % fclose(handles.KSyncDepth);
            end
        end
        
        function handles = connect2ER60(app, handles)
            
            try
            
                %% Get/set ER60 connection settings
                handles.ER60RequestID = 1;  % Init. ER60 request ID
            
                %% Request server info and open connection
            
                % Create UDP port object
                handles.ER60 = udpport("byte", "IPV4");
            
                % Send request server info
                write(handles.ER60, ['RSI' char(0)], "char", ...
                    handles.settings.ER60IP, ...
                    str2double(handles.settings.RemotePort));
            
                % Read header. If nothing is sent back, then a connection setting must
                % be wrong.
                try
                    header = read(handles.ER60,4,'char');	% Read header
                    if ~contains(header, 'SI2')
                        error('Incorrect response.  Check IP address')
                    end
                catch ME
                    error('No response from ER60/EK80.  Check settings')
                end
            
                % Get remote commandport which should be used to set up subscriptions and
                % continuously receive and respond alive messages for this connection.
                read(handles.ER60,64,'char');                   % Application Type
                read(handles.ER60,64,'char');                   % Application name
                read(handles.ER60,128,'char');                  % Application description
                read(handles.ER60,1,'int32');                   % Application ID
                commandPort = read(handles.ER60,1,'int32');	    % Command Port
                disp(commandPort)
                read(handles.ER60,1,'int32');                   % Mode
                read(handles.ER60,64,'char');                   % Host name
                        
                %% Connect to ER60 server
            
                % Try to connect with a user and password which must be defined in
                % server ER60 application (Users and Passwords dialogue).
                write(handles.ER60, ['CON' char(0) 'Name:' ...
                    handles.settings.ER60Name ';Password:' ...
                    handles.settings.ER60Password char(0)], 'char', ...
                    handles.settings.ER60IP, commandPort);
            
                % Receive response
                header = read(handles.ER60,4,'char');
                if contains(header, 'RES')
                    read(handles.ER60,4,'char');
                    read(handles.ER60,22,'char');
                    response = read(handles.ER60,1400,'char');
                else
                    error('Unknown response: %s', header);
                end
            
                % Get CLIENTID
                handles.ER60CLIENTID = regexp(response, 'ClientID:(\d+),', 'tokens');
                handles.ER60CLIENTID = handles.ER60CLIENTID{:}{:};
            
                % Initialiaze client sequence number
                handles.ER60CLIENTSEQNO = 1;
            
                %% Get transceiver IDs
            
                % Send request to get transceiver IDs
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/Channels</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                transceiverID = readbetween(app, '<value dt="8200">','</value>',response);
                handles.ER60transceiverID = regexp(transceiverID, '[^,]*', 'match');    % transceiver IDs for each frequency
            
                % Get Nominal frequencies
                for i = 1:length(handles.ER60transceiverID)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/Frequency</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.freqs(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
                end
            
                % Sort frequencies to ensure they are ordered correctly
                [handles.freqs, temp] = sort(handles.freqs);
                handles.ER60transceiverID = handles.ER60transceiverID(temp);

                % Remove any EC150 references
                idx = contains(handles.ER60transceiverID, 'EC150');
                handles.freqs(idx) = [];
                handles.ER60transceiverID(idx) = [];
                        
            catch ME
            
                % Delete all instruments
            %     delete(instrfindall)
                if isfield(handles, 'ExtDepth')
                    if isvalid(handles.ExtDepth); delete(handles.ExtDepth); end
                end
                if isfield(handles, 'KSyncDepth')
                    if isvalid(handles.KSyncDepth); delete(handles.KSyncDepth); end
                end
                if isfield(handles, 'ER60')
                    if isvalid(handles.ER60); delete(handles.ER60); end
                end
                if isfield(handles, 'u1')
                    if isvalid(handles.u1); delete(handles.u1); end
                end
            
            
                error(ME.message);
            end
        end
        
        function enableInputs(app, handles)
            
            %% Change Stop button
            set(handles.startButton, 'Value', 0)
            set(handles.startButton, 'BackgroundColor', 'g')
            set(handles.startButton, 'String', 'Start')
                        
            % Enable K-Sync inputs while operating
            set(handles.checkboxKSync, 'Enable', 'On')
                        
            % Disable Set Ping Interval input and stop timer
            set(handles.setManualPI, 'Value', 0)
            set(handles.setManualPI, 'Enable', 'off')
            set(handles.manualPI, 'Enable', 'off')
            
            % Enable false bottom inputs if selected
            set(handles.fixFB, 'Enable', 'on');
            if get(handles.fixFB, 'Value')
                set(handles.bathyFile, 'Enable', 'on');
                set(handles.bathyFileBrowse, 'Enable', 'on');
                set(handles.loadBathy, 'Enable', 'on');
                set(handles.bathyLoadStatus, 'Enable', 'inactive');
            end
        end
        
        function handles = findbottom(app, handles)
            % Function to find the bottom depth
            %
            % The algorithm should be as follows:
            %   1.  Noise filter the data
            %   2.  Only keep samples within a desired detection window.
            %   3.  Only keep samples in which Sv's from all frequencies (that haven't
            %       been removed due to noise) are above some threshold.  This should
            %       leave samples that are either from the bottom, fish schools, a
            %       false bottom, or the transmit pulse.
            %   4.  From those samples, calculate the VMR to separate the bottom from
            %       fish school.  In some cases a sample will only have Sv data from
            %       one frequency, but in that scenario it is not possible to
            %       distinguish the bottom from a fish school.
            %   5.  Find the first sample that has VMR > -23.
            %   6.
            
            %% Define some processing parameters
            
            Sv_threshold = -50;     % Sv threshold value
            VMR_threshold = -30;    % VMR threshold
            noise_threshold = -60;  % Noise floor Sv beyond which samples are removed
            rollingAvgLength = 3;   % Number of meters to use for smoothing data
            
            %% Calculate ranges for each frequency
            
            r = arrayfun(@(x,y) linspace(0, x, y), handles.currRange, ...
                cellfun(@length, handles.Sv), 'UniformOutput', 0);

            %% Check to see if r is same size as the variables


            %% Convert data to same sample rate and size
            % Data from each echosounder might span different ranges and contain
            % different sample rates, so put them all onto a common grid
            
            % Compute sample rates of each echosounder (samples per meter)
            Fs = cellfun(@length, handles.Sv) ./ cellfun(@(x) x(end)-x(1), r);
            
            % Create range vector spanning the longest logging range using the lowest
            % sample rate
            range = (0:1/min(Fs):max(handles.currRange))';
            
            % Cycle through each frequency and interpolate at those ranges
            Sv = nan(length(range), length(handles.freqs));
            Pr = nan(length(range), length(handles.freqs));
            Angle = cell(length(handles.freqs),1);
            for i = 1:length(handles.freqs)
                % fprintf('r and Pr lengths = [%d %d]\n', length(r{i}), length(handles.Power{i}))
                Sv(:,i) = interp1(r{i}, double(handles.Sv{i}), range, 'linear', NaN);
                Pr(:,i) = interp1(r{i}, double(handles.Power{i}), range, 'linear', NaN);
                Angle{i} = interp1(r{i}, double(handles.Angle{i}), range, 'linear', NaN);
            end
            
            %% Downsample data to lowest sample rate
            % minSize = min(cellfun(@length, handles.Sv));
            % handles.Sv = cellfun(@(x) resample(double(x), minSize, length(x)), handles.Sv, 'UniformOutput', 0);
            % handles.Power = cellfun(@(x) resample(double(x), minSize, length(x)), handles.Power, 'UniformOutput', 0);
            % handles.Angle = cellfun(@(x) resample(double(x), minSize, length(x)), handles.Angle, 'UniformOutput', 0);
            
            %% Convert power and angle data
            
            % Sv and Pr data are give in EK500 format, so convert from that
            Sv = Sv * 10*log10(2)/256;
            Pr = Pr * 10*log10(2)/256;
            
            % If Sv is empty (e.g. only 1 frequency that is in passive mode), then set
            % depth to NaN and return to invoking function
            if isempty(Sv)
                handles.depth = NaN;
                disp('Could not find bottom, so saving and exiting function')
                save(fullfile('C:\ProgramData\EAL', 'Bottom Data', [char(datetime('now', Format='yyyyMMdd''T''HHmmss')) '_bottom.mat']), 'Sv', 'Pr', 'Angle')
                return
            end
            
            % Parse out the phase data to along- and athwart-ship and convert to
            % mechanical angles
            % Phase = double(reshape(typecast(int16(Angle),'int8'), 2, ...
            %     length(x))'), handles.Angle, 'UniformOutput', 0);
            Phase = cellfun(@(x) double(reshape(typecast(int16(x),'int8'), 2, ...
                length(x))'), Angle, 'UniformOutput', 0);
            
            % Get Alongship angles
            Alongship = cell2mat(cellfun(@(x,y) x(:,1) * 1.40625 / y, ...
                Phase, num2cell(handles.AlongshipAngleSensitivity)', ...
                'UniformOutput', 0)');
            
            % Get Athwartship angles
            Athwartship = cell2mat(cellfun(@(x,y) x(:,2) * 1.40625 / y, ...
                Phase, num2cell(handles.AthwartshipAngleSensitivity)', 'UniformOutput', 0)');
            
            %% Remove passive-mode data
            
            % Ignore Sv, Pr, and Angles for any frequencies that are in passive mode
            for i = 1:length(handles.freqs)
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/ChannelMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                currMode = readbetween(app, '<value dt="3">','</value>',response);
            
                % If mode is passive, then make all Sv's and Pr's NaNs
                if strcmp(currMode, '1')
                    Sv(:,i) = NaN;
                    Pr(:,i) = NaN;
                    Alongship(:,i) = NaN;
                    Athwartship(:,i) = NaN;
                end
            end

            %% Store some temporary variables to save for debugging

            % range_save = range;
            % Sv_save = Sv;
            % Pr_save = Pr;
            % Angle_save = Angle;
            % Alongship_save = Alongship;
            % Athwartship_save = Athwartship;
            % currRange_save = handles.currRange;
            % rangeWindow_save = handles.RangeWindow;

            %% Remove 120 and 200 kHz data to see if it improves bottom detection
            for i = 4:5
                Sv(:,i) = NaN;
                Pr(:,i) = NaN;
                Alongship(:,i) = NaN;
                Athwartship(:,i) = NaN;
            end
            
            %% Only keep data in desired range
            
            ridx = range > 3 & range > handles.RangeWindow(1) & range < handles.RangeWindow(2);
            
            Sv(~ridx,:) = [];
            Pr(~ridx,:) = [];
            Alongship(~ridx,:) = [];
            Athwartship(~ridx,:) = [];
            range(~ridx) = [];
            
            %% Smooth Sv
            for i = 1:size(Sv,2)
                N = ceil(rollingAvgLength / (handles.c/2) / (handles.tau(i)/4));
                Sv(:,i) = 10*log10(filter(ones(1,N)/N, 1, 10.^(Sv(:,i)./10)));
            end
            
            %% Noise filter the data
            % We want to find the range at which the noise floor ramps up high enough
            % that it's Sv-value is close to what we expect for the seabed (e.g. SNR <
            % 10). For examples, if we expect the seabed to be above -40 dB, we find
            % what range our noise floor would equal -50 dB, then remove all samples
            % beyond that range, and assume the SNR is too low to accurately detect the
            % seabed
            
            % Only noise filter if there are currently noise estimates
            if ~any(isnan(handles.noiseFloor))
            
                % Calculate noise floor Sv for each transducer
                Sv_noise = repmat(handles.noiseFloor, length(range), 1) + ...
                    repmat(20*log10(range), 1, size(Sv, 2)) + ...
                    2*range*handles.alpha - ...
                    repmat(10*log10(handles.Pt.*(10.^(handles.G0./10)).^2.*(handles.c./handles.freqs).^2.*handles.c.*handles.tau.*(10.^(handles.psi./10))./(32*pi^2)), length(range), 1) - ...
                    repmat(2*handles.Sa_corr, length(range), 1);
            
                % Remove all samples when the noise floor is above some threshold
                idx = Sv_noise > noise_threshold;
                Sv(idx) = NaN;
                Pr(idx) = NaN;
                Alongship(idx) = NaN;
                Athwartship(idx) = NaN;
            end
            
            %% Calculate VMR
            % If only one sample is available then the variance is 0 and the VMR would
            % be -Inf. In that case, we want to still be able to detect the seabed from
            % the one frequency, so don't eliminate it using the VMR filter.
            
            % Calculate echo amplitude
            e = 10.^(Sv./20);
            
            % Calculate VMR
            VMR = 10*log10(var(e, 0, 2, 'omitnan')./mean(e, 2, 'omitnan'));
            
            % For samples that didn't have 3 or more frequency datapoints, set the VMR
            % to NaN so that it doesn't get filtered out.
            idx = sum(~isnan(Sv),2) < 3;
            VMR(idx) = NaN;
            
            %% Find seabed samples
            
            % Find Svs that are above the threshold and VMR is not less
            % than its threshold
            idx = find(min(Sv, [], 2, 'omitnan') > Sv_threshold & ~(VMR < VMR_threshold));
            
            % If no samples exist, exit
            if isempty(idx)
                handles.depth = NaN;
                handles.lastDepths = [handles.lastDepths(2:end); NaN];
                handles.lastSlopes = [handles.lastSlopes(2:end); NaN];
                handles.lastDZHs = [handles.lastDZHs(2:end); NaN];
                handles.lastRoughnesses = [handles.lastRoughnesses(2:end); NaN];

                disp('No seabed samples exist, so exiting function')
                % save(fullfile('C:\ProgramData\EAL', 'Bottom Data', [char(datetime('now', Format='yyyyMMdd''T''HHmmss')) '_bottom.mat']), ...
                %                 'range_save', 'Sv_save', 'Pr_save', 'Angle_save', 'Alongship_save', 'Athwartship_save', 'currRange_save', 'rangeWindow_save')
                return
            end
            
            % Find seabed sample by locating the maximum Sv
            [~, idx2] = max(max(Sv(idx,:), [], 2, 'omitnan'));
            idx = idx(idx2);
            
            % Find sample to the left when Sv drops below the threshold
            idx1 = find(min(Sv(1:idx,:), [], 2, 'omitnan') < Sv_threshold, 1, 'last');
            if isempty(idx1); idx1 = 1; end
            
            % Find sample to the right when Sv drops below the threshold
            idx2 = idx-1 + find(min(Sv(idx:end,:), [], 2, 'omitnan') < Sv_threshold, 1);
            if isempty(idx2); idx2 = size(Sv,1); end
            
            %% Calculate bottom using plane fit
            
            % Take only phase data from those indices
            alpha = Alongship(idx1:idx2, :);
            beta = Athwartship(idx1:idx2, :);
            
            % Concert to x, y, and z coordinates
            x = sind(alpha) .* cosd(beta) .* ...
                repmat(range(idx1:idx2),1,size(alpha,2)) ./ ...
                sqrt(1-sind(alpha).^2.*sind(beta).^2);
            
            y = cosd(alpha) .* sind(beta) .* ...
                repmat(range(idx1:idx2),1,size(alpha,2)) ./ ...
                sqrt(1-sind(alpha).^2.*sind(beta).^2);
            
            z = cosd(alpha) .* cosd(beta) .* ...
                repmat(range(idx1:idx2),1,size(alpha,2)) ./ ...
                sqrt(1-sind(alpha).^2.*sind(beta).^2);
            
            % Cycle through each frequency
            depth = nan(1, size(Pr,2));
            dzh = nan(1, size(Pr,2));
            roughness = nan(1, size(Pr,2));
            slope = nan(1, size(Pr,2));
            for i = 1:size(Pr,2)
            
                temp = [x(:,i) y(:,i) ones(length(x(:,i)),1)];     % Create variable matrix
            
                % If matrix is non-singular, then perform least-squares regression
                if rcond(temp'*temp) > 1e-15
            
                    % Perform least squares regression
                    BETA = (temp'*temp)\temp'*z(:,i);
            
                    % Make sure depth is positive
                    if BETA(3) < 0
                        depth(i) = mean(z(:,i));
                        dzh(i) = depth(i) - range(idx1);
                    else
            
                        % Store depth and dead zone height
                        depth(i) = BETA(3);
                        dzh(i) = BETA(3) - range(idx1);
            
                        % Calculate residual sum of squares (RSS) for null hypothesis
                        RSS1 = sum((z(:,i)-mean(z(:,i))).^2);
            
                        % Calculate RSS for plane fit
                        RSS2 = sum((z(:,i)-temp*BETA).^2);
            
                        % Calculate F-statistic
                        F = ((RSS1-RSS2)/(3-1))/(RSS2/(length(z(:,i))-3));
            
                        % Calculate p-value
                        p = 1-fcdf(F, (3-1), length(z(:,i))-3);
            
                        % If p < .05, then we can reject the null hypothesis, indicating
                        % that our plane fit is statistically significant and we can
                        % calculate slope and roughness
                        if p < 0.05
                            roughness(i) = std(z(:,i)-temp*BETA, 'omitnan');
                            slope(i) = acosd(1./sqrt(BETA(1).^2+BETA(2).^2+1));
                        end
                    end
            
                % Otherwise, data is not suitable for a plane fit, so simply use the
                % mean of Z as the depth and use it calculate dead zone height.
                else
                    depth(i) = mean(z(:,i));
                    dzh(i) = depth(i) - range(idx1);
                end
            end
            
            % Store depth in handles structure
            handles.depth = mean(depth, 'omitnan');
            fprintf('Computed depth = %.2f\n', handles.depth)

            if isnan(handles.depth)
                save(fullfile('C:\ProgramData\EAL', 'Bottom Data', [char(datetime('now', Format='yyyyMMdd''T''HHmmss')) '_bottom.mat']), 'Sv', 'Pr', 'Angle')
            end
            
            % Update plotting variables
            handles.lastDepths = [handles.lastDepths(2:end); handles.depth];
            handles.lastSlopes = [handles.lastSlopes(2:end); mean(slope, 'omitnan')];
            handles.lastDZHs = [handles.lastDZHs(2:end); mean(dzh, 'omitnan')];
            handles.lastRoughnesses = [handles.lastRoughnesses(2:end); mean(roughness, 'omitnan')];
        end
        
        function getData(app, obj)
            % CHANGE ER60 PARAMETERS BASED ON OBSERVED BOTTOM DEPTH
            %   This function is periodically called by a timer object.  The function
            %   begins reading the UDP buffer to collect the most recent ping data
            %   returned by the ER60.  When all the data for the most recent ping has
            %   been collected, a bottom detection is then performed.  The ER60
            %   settings are then updated according to the bottom detection.
            
            handles = guidata(obj);      % Get latest handles structure
            
            % Encase in try/catch statement in case an error occurs.
            try
            
                % If getData() is already currently running, then exit callback
                if handles.currentlyRunning == 1
                    return
            
                % Otherwise, update variable which signals it is running and update
                % handles structure. Thus, if getData() is called while it is
                % currently running (i.e. event queue is flushed), it will ignore that
                % callback.
                else
                    handles.currentlyRunning = 1;       % Set flag to 1
                    guidata(handles.output, handles);   % Update handles structure
                end
            
                % Issue full drawnow command to flush event queue and process any
                % figure changes
                drawnow
            
                % Get data if the start button is still pressed.  Otherwise, stop
                % subscription and GUI.
                if get(handles.startButton, 'Value')
            
                    % Send alive messages to ER60 and EK80 objects
                    handles = sendAliveMessage(app, handles);
            
                    % If emitting deep ping, then wait until 5 seconds is up to prevent
                    % false bottoms from being detected as the actual bottom
                    if handles.emittingDeepPing
                         while toc(handles.deepPingTimer) < 5
            
                            % Send alive messages to ER60 and EK80 objects
                             handles = sendAliveMessage(app, handles);
                         end
                    end
            
                    % Get ER60 ping state
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OperationControl/OperationMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    pingState = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                    % If state is inactive (16), then Z-Mux is running, so break out of
                    % callback
                    if isequal(pingState, 16)
                        set(handles.pingTime, 'String', 'EK60s not pinging')
                        drawnow nocallbacks
            %             drawnow expose update
            
                        % Change currently running variable to 0 so that getData()
                        % will start looking for data again.
                        handles.currentlyRunning = 0;
            
                        % Save handles structure then exit callback
                        guidata(handles.output, handles);
                        return
            
                    % Otherwise, state is active, so we can collect data
                    else
            
                        % If emitting a deep bottom ping, then put ER60 in single step and
                        % send trigger signal
                        if handles.emittingDeepPing
            
                            fprintf('Emitting deep ping\n');
            
                            % Put in Single step mode.  This needs to be done again in case
                            % the Z-Mux put it back into Interval mode
                            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Single step</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
                            handles = sendrequest(app, handles, rString);
            
                            % Wait until transducers are ready to ping
                            pingStatus = 1;
                            while ~isequal(pingStatus,0)
                                % Get ER60 ping status
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/PingStatus</paramName><time>0</time></GetParameter></method></request>' char(0)];
                                [handles, response] = sendrequest(app, handles, rString);
                                pingStatus = str2double(readbetween(app, '<value dt="3">','</value>',response));
                            end
            
                            % Emit ping
                            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/ExecuteNextPing</paramName><paramValue>1</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                            handles = sendrequest(app, handles, rString);
            
                        % Otherwise, ensure that echosounder is in correct mode
                        else
            
                            % Get ER60 ping mode.
                            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                            [handles, response] = sendrequest(app, handles, rString);
                            pingMode = readbetween(app, '<value dt="8">','</value>',response);
            
                            % If not using K-Sync but removing bottoms, ping mode
                            % should be Interval
                            if ~get(handles.checkboxKSync, 'Value') && get(handles.fixFB, 'Value')
            
                                % Only change if it's not already Interval
                                if ~strcmp(pingMode, 'Interval')
                                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Interval</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
                                    handles = sendrequest(app, handles, rString);
                                end
            
                            % Otherwise, ping mode should be Maximum
                            else
            
                                % Only change if not already Maximum
                                if ~strcmp(pingMode, 'Maximum')
                                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Maximum</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
                                    handles = sendrequest(app, handles, rString);
                                end
                            end
            
            %                 % If using K-Sync, change mode to maximum if it isn't
            %                 if get(handles.checkboxKSync, 'Value')
            %                     if ~strcmp(pingMode, 'Maximum')
            %                         rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Maximum</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
            %                         handles = sendrequest(handles, rString);
            %                     end
            %
            %                 % If not using K-Sync, change mode to interval if it isn't
            %                 else
            %                     if ~strcmp(pingMode, 'Interval')
            %                         rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Interval</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
            %                         handles = sendrequest(handles, rString);
            %                     end
            %                 end
            
                            % If ping mode was single step, need to make active again
                            if strcmp(pingMode, 'Single step')
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>OperationControl/OperationMode</paramName><paramValue>17</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                                handles = sendrequest(app, handles, rString);
                            end
                        end
                    end
            
                    % If getting bottom depths from an external sensor, then there's no
                    % need to wait and collect subscription data. So first check to see
                    % if data is available from an external sensor.
                    if ~isempty(handles.settings.ExtDepthIP)
            
                        % Encase in try/catch block, in case a problem occurs when
                        % trying to read depth from external system
                        try

                            % Read depth
                            if isa(handles.ExtDepth, 'udpport.datagram.UDPPort')
                                flush(handles.ExtDepth)
                                depth = read(handles.ExtDepth,1,"char");
                                depth = split(depth.Data, ',');
                                depth = str2double(depth{4});
                            else
                                % fopen(handles.ExtDepth); 	% Open TCP/IP object
                                flush(handles.ExtDepth)
                                depth = str2double(readline(handles.ExtDepth));
                                % fscanf(handles.ExtDepth);  	% Read and discard first packet
                                % depth = str2double(fscanf(handles.ExtDepth));	% Read depth
                                % fclose(handles.ExtDepth);  	% Close object
                            end

                            % Since the instrument providing the external
                            % depth might use a different sound speed than
                            % the EK80, need to convert to the equivalent
                            % EK80 depth so that the ping rate calculation
                            % is accurate
                            depth = depth * handles.c / str2double(handles.settings.ExtDepthSV);
            
                            % Do some quality control checks on the depth
                            if depth <= 5 || abs(depth-handles.depth) > 100
                                clear depth
                            end
            
                        % If an error occurred, report it
                        catch ME
                            clear handles.ExtDepth
                            clear depth
                            fprintf('Unable to read external depth sensor: "%s"\n', ME.message)
                        end
            
                        % If depth was obtained, use it. Otherwise, do bottom detection
                        if exist('depth', 'var')
                            disp('Depth obtained from external sensor!')
                            handles.depth = depth;
            
                            handles.currTime = datetime('now');
            
                            % Update plotting variables. If depth obtained from
                            % external sensor, then we don't know the slope, DZH, or
                            % roughness, so make those NaNs.
                            handles.lastDepths = [handles.lastDepths(2:end); handles.depth];
                            handles.lastSlopes = [handles.lastSlopes(2:end); NaN];
                            handles.lastDZHs = [handles.lastDZHs(2:end); NaN];
                            handles.lastRoughnesses = [handles.lastRoughnesses(2:end); NaN];
                        end
                    end
            
                    % If depth variable doesn't exist, either because there is no
                    % external sensor or depth wasn't able to be obtained from the
                    % external sensor, then download the subscription data and use it
                    % to detect the bottom.
                    if ~exist('depth', 'var')
            
                        fprintf('Waiting for ping data...\n');
            
%                         % Open UDP object
%                         fopen(handles.u1);
                        flushinput(handles.u1)
            
                        % Continuously read UDP buffer until all data is received for a ping
                        while 1
            
                            % Issue full drawnow command to register if user pressed Stop
                            % button while trying to read ping data.
                            drawnow
            
                            % If stop button pressed, exit callback
                            if ~get(handles.startButton, 'Value')
                                handles.currentlyRunning = 0;
                                guidata(handles.output, handles);
                                return;
                            end
            
                            % Continually check if bytes are available in the udp object.  If
                            % not, check if ER60 is still active.  If not, then exit callback
                            subtimer = tic;
                            while isequal(handles.u1.NumBytesAvailable,0)
            
                                % Send alive messages to ER60 and EK80 objects
                                handles = sendAliveMessage(app, handles);
            
                                % Get ER60 ping state
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OperationControl/OperationMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                                [handles, response] = sendrequest(app, handles, rString);
                                pingState = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                                % If inactive, exit callback
                                if isequal(pingState, 16)
%                                     fclose(handles.u1);
                                    set(handles.pingTime, 'String', 'EK60s not pinging')
                                    drawnow nocallbacks
            %                         drawnow expose update
            
                                    % Change currently running variable to 0 so that
                                    % getData() will start looking for data again.
                                    handles.currentlyRunning = 0;
            
                                    guidata(handles.output, handles);
                                    return
                                elseif toc(subtimer) > 10
                                    error('Not receiving subscription data.  Resetting ER60...');
                                end
                            end
            
                            temp = nan(1,3);
                            while ~isequal(temp, double('PRD'))
                                temp = [temp(2:3) double(read(handles.u1, 1, 'int8'))];
                            end
                            read(handles.u1, 1, "char");        % Read extra byte
            
                            % Read rest of header information
                            read(handles.u1,1,'int32');                 	% Sequence number
                            subID = read(handles.u1,1,'int32');            % Subscription ID
                            currMsg = read(handles.u1,1,'uint16');       	% Current message number
                            totalMsg = read(handles.u1,1,'uint16');      	% Total number of UDP messages
                            numbytes = read(handles.u1,1,'uint16');    	% Number of bytes in data field
            
                            % If it's not the first message and all subscription variables are
                            % empty, then read and discard that datagram.
                            if ~isequal(currMsg, 1) && ...
                                    isequal(sum(~cellfun(@isempty, handles.Power)), 0) && ...
                                    isequal(sum(~cellfun(@isempty, handles.Sv)), 0) && ...
                                    isequal(sum(~cellfun(@isempty, handles.Angle)), 0)
            
                                fprintf('Not first message and all data empty...discarding...\n');
                                read(handles.u1, numbytes, 'int8');        % Discard data
            
                            % Otherwise, the data may be useful
                            else
            
                                % If first message in datagram, get time
                                if isequal(currMsg, 1)
                                    time1 = read(handles.u1,1,'int32');        % Time part 1
                                    time2 = read(handles.u1,1,'int32');        % Time part 2
            
                                    % Calculate the ping time
                                    time = double(typecast([int32(time1) int32(time2)], ...
                                        'int64'))*100/1e9/60/60/24 + datetime(1601,1,1);
            
                                    % Read datagram data if enough bytes exist
                                    data = read(handles.u1,numbytes-8, 'int8')';
            
                                    % If timestamp has changed but subscription data exists,
                                    % then discard current existing contents and treat as a new
                                    % ping.
                                    if ~isequal(time, handles.currTime) && ...
                                            (~isequal(sum(~cellfun(@isempty, handles.Power)), 0) || ...
                                            ~isequal(sum(~cellfun(@isempty, handles.Sv)), 0) || ...
                                            ~isequal(sum(~cellfun(@isempty, handles.Angle)), 0))
            
                                        % Reinitialize subscription data (discard old contents)
                                        handles.Power = cell(length(handles.freqs), 1);
                                        handles.Sv = cell(length(handles.freqs), 1);
                                        handles.Angle = cell(length(handles.freqs), 1);
            
                                        fprintf('Timestamp differs...dumping old data.\n');
                                    end
            
                                    handles.currTime = time;    % Store current timestamp
            
                                % Otherwise, it's data that needs to be appended to existing
                                % data
                                else
            
                                    % Read datagram data only if that number of bytes exist
                                    data = read(handles.u1, numbytes, 'int8')';
                                end
            
                                % Store data in appropriate subscription cell array
                                if any(subID == handles.PowerID)       % If Power data
                                    idx = find(subID == handles.PowerID);
                                    handles.Power{idx} = [handles.Power{idx}; data];
                                elseif any(subID == handles.SvID)  	% If Sv data
                                    idx = find(subID == handles.SvID);
                                    handles.Sv{idx} = [handles.Sv{idx}; data];
                                elseif any(subID == handles.AngleID)     % If Angular data
                                    idx = find(subID == handles.AngleID);
                                    handles.Angle{idx} = [handles.Angle{idx}; data];
                                end
            
                                % If all the frequencies for all data subscriptions have data,
                                % and it's the last datagram (currMsg equals totalMsg), then
                                % break out of while loop and begin processing data
                                if currMsg == totalMsg && ...
                                        isequal(sum(cellfun(@isempty, handles.Sv)), 0) && ...
                                        isequal(sum(cellfun(@isempty, handles.Power)), 0) && ...
                                        isequal(sum(cellfun(@isempty, handles.Angle)), 0)
                                    break;
                                end
                            end
                        end
            
%                         % Close UDP object
%                         fclose(handles.u1);
            
                        % Convert from int8 to int16
                        handles.Sv = cellfun(@(x) typecast(int8(x), 'int16'), ...
                            handles.Sv, 'UniformOutput', 0);
                        handles.Power = cellfun(@(x) typecast(int8(x), 'int16'), ...
                            handles.Power, 'UniformOutput', 0);
                        handles.Angle = cellfun(@(x) typecast(int8(x), 'int16'), ...
                            handles.Angle, 'UniformOutput', 0);
            
                        % Sv, power, and angle data may have differing number of
                        % samples, so trim to smallest variable
                        for i = 1:length(handles.Sv)

                            % If Sv, Power, and Angle data have differing
                            % number of samples, pad the smaller variables
                            % with NaNs
                            if length(handles.Sv{i}) ~= length(handles.Power) && ...
                                length(handles.Sv{i}) ~= length(handles.Angle{i})

                                % disp('Sv, Power, and Angle variables have differing lengths, so padding smaller variables')

                                % fprintf('Sv, Power, and Angle lengths = [%d %d %d]\n', ...
                                %     length(handles.Sv{i}), length(handles.Power{i}), length(handles.Angle{i}))

                                % Pad the smaller variables with NaNs
                                maxSize = max([length(handles.Sv{i}), length(handles.Power{i}), ...
                                    length(handles.Angle{i})]);
                                handles.Sv{i} = [handles.Sv{i}; nan(maxSize-length(handles.Sv{i}),1)];
                                handles.Power{i} = [handles.Power{i}; nan(maxSize-length(handles.Power{i}),1)];
                                handles.Angle{i} = [handles.Angle{i}; nan(maxSize-length(handles.Angle{i}),1)];

                                % fprintf('New Sv, Power, and Angle lengths = [%d %d %d]\n', ...
                                %     length(handles.Sv{i}), length(handles.Power{i}), length(handles.Angle{i}))
                            end

                            % minSize = min([length(handles.Sv{i}), length(handles.Power{i}), ...
                            %     length(handles.Angle{i})]);
                            % handles.Sv{i} = handles.Sv{i}(1:minSize);
                            % handles.Power{i} = handles.Power{i}(1:minSize);
                            % handles.Angle{i} = handles.Angle{i}(1:minSize);
                        end
            
                        handles = findbottom(app, handles);
                    end
            
                    % Display results of detection
                    fprintf('Detected bottom = %0.2f\n', handles.depth);
            
                    % Update ER60 settings using bottom detection results
                    handles = updateSettings(app, handles);
            
                    % Display ping time
                    fprintf('Ping time = %s\n', datetime(handles.currTime, 'Format', 'ddMMMyyyy HH:mm:ss.SSS'));
            
                    % Make passive noise measurements if checkbox is selected
                    if get(handles.checkboxNoise, 'Value')
            
                        % If noise timer exists, check to see if it's time for a new
                        % measurements
                        if isfield(handles, 'noiseTimer')
            
                            % If time for a new measurements, do it
                            if toc(handles.noiseTimer) > 60*str2double(handles.settings.PassiveInt)
                                handles = collectNoise(app, handles);
            
                            % Otherwise, display when the next measurement occurs
                            else
                                fprintf('Next noise measurements in %.0f seconds\n', 60*str2double(handles.settings.PassiveInt) - toc(handles.noiseTimer))
                            end
            
                        % If the timer doesn't exist, then collect noise data
                        else
                            handles = collectNoise(app, handles);
                        end
            
                    % If user doesn't wish to collect noise measurements, set to NaN
                    else
                        handles.noiseFloor = nan(1, length(handles.freqs));
                    end
            
                    fprintf('--------------------------\n');    % Add spacer
            
                    % Re-initialize the data subscription variables
                    handles.currTime = NaT;
                    handles.Power = cell(length(handles.freqs), 1);
                    handles.Sv = cell(length(handles.freqs), 1);
                    handles.Angle = cell(length(handles.freqs), 1);
            
                    % Update GUI and save handles structure
                    drawnow nocallbacks
            %         drawnow expose update
                    handles.currentlyRunning = 0;       % Reset currently running flag
                    guidata(handles.output, handles);
            
                % If Stop button is pressed, then stop timers, unsubscribe from data, and
                % close connection to ER60.
                else
            
                    % Stop timer that has EAL collect UDP ping data
                    stop(handles.subcriptionTimer);
            
                    % Stop manual ping timer
                    stop(handles.setPITimer)
            
                    % Remove subscription IDs so that we know we aren't subscribed
                    handles = rmfield(handles, {'PowerID', 'SvID', 'AngleID'});
            
                    % Send commands to ER60 object if it is valid
                    if isvalid(handles.ER60)
            
                        %% Set the ping rate based on the logging range
            
                        % Calculate the minimum PI given the logging range
                        procTime = str2double(handles.settings.ProcBuf);
                        PI = procTime + (max(handles.normRange) * 2/handles.c);
            
                        % If using the K-Sync, the minimum ping interval must take into account
                        % the time spent in groups not containing the ER60/EK80
                        if get(handles.checkboxKSync, 'Value')
                            PI = PI + str2double(handles.settings.KSyncAdjust);
            
                            depthM = PI * 1500/2;
                            NMEA = sprintf('$SDDPT,%.2f,0.00,*', depthM);
            
                            fprintf('Depth to K-Sync = %.2f\n', depthM)

                            % Calculate checksum
                            temp = double(NMEA(2:end-1));
                            checksum = 0;
                            for j = 1:length(temp)
                                checksum = bitxor(checksum, temp(j));
                                checksum = uint16(checksum);
                            end
                            checksum = double(checksum);
                            checksum = dec2hex(checksum);
                            if length(checksum) == 1; checksum = strcat('0',checksum); end
            
                            % Send depth to K-Sync 5 times every 1/2 second
                            % fopen(handles.KSyncDepth);
                            for i = 1:5
                                writeline(handles.KSyncDepth, ...
                                    sprintf('%s%s%s', NMEA, checksum, [char(13) newline]), ...
                                    handles.settings.KSyncIP, str2double(handles.settings.KSyncUDPPort))
                                % fprintf(handles.KSyncDepth, sprintf('%s%s%s', NMEA, checksum, [char(13) newline]));
                                java.lang.Thread.sleep(500);
                            end
                            % fclose(handles.KSyncDepth);
                        end
                        
                        % Obtain variable indicating if the EK80 is using individual logging
                        % ranges for each transducer
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/IndividualChannelRecordingRange</paramName><time>0</time></GetParameter></method></request>' char(0)];
                        [handles, response] = sendrequest(app, handles, rString);
                        IndChannelFlag = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                        % If flag is 1, then EK80 is set to record to individual ranges for
                        % each transducer. So will need to cycle through each WBT and set the
                        % sample ranges
                        if IndChannelFlag
            
                            % Cycle through frequencies
                            for i = 1:length(handles.freqs)
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/' handles.ER60transceiverID{i} '_ES/Range</paramName><paramValue>' int2str(handles.normRange(i)) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                                handles = sendrequest(app, handles, rString);
                            end
            
                        % Otherwise, only need to set the single logging range, in which case
                        % we'll use the maximum of the logging ranges (should ideally all be
                        % the same if the user wants that option).
                        else
                            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/SampleRange</paramName><paramValue>' int2str(max(handles.normRange)) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                            handles = sendrequest(app, handles, rString);
                        end
            
            %             % Put logging range back to original setting
            %             rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/SampleRange</paramName><paramValue>' int2str(max(handles.normRange)) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
            %             handles = sendrequest(handles, rString);
            %             set(handles.lRange, 'String', max(handles.normRange))
            
                        % Set the ping interval to the maximum rate
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/Interval</paramName><paramValue>' int2str(PI*1000) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                        handles = sendrequest(app, handles, rString);
                        set(handles.pInterval, 'String', num2str(PI))
            
                        % Put echogram ranges and bottom detection ranges back to the logging range
                        for i = 1:length(handles.freqs)
            
                            rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' int2str(handles.normRange(i)) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                            rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/UpperDetectorLimit</paramName><paramValue>30</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                            rString3 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/LowerDetectorLimit</paramName><paramValue>' int2str(handles.normRange(i)) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                            rString4 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/ChannelMode</paramName><paramValue>0</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
            
                            handles = sendrequest(app, handles, rString1);
                            handles = sendrequest(app, handles, rString2);
                            handles = sendrequest(app, handles, rString3);
                            handles = sendrequest(app, handles, rString4);
                        end
            
                        % Get ER60 ping state
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OperationControl/OperationMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                        [handles, response] = sendrequest(app, handles, rString);
                        pingState = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                        % If inactive (16), then Z-Mux is running, in which case it will put it
                        % interval mode and start pinging once it's done.  Thus, we don't need
                        % to do anything unless the ER60 is currently active and in single step
                        % mode.
                        if isequal(pingState, 17)
            
                            % Get current ER60 ping mode.
                            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><time>0</time></GetParameter></method></request>' char(0)];
                            [handles, response] = sendrequest(app, handles, rString);
                            pingMode = readbetween(app, '<value dt="8">','</value>',response);
            
                            % If it was in Single step mode, then changing it to interval or
                            % maximum mode made it inactive.  So make it active again
                            if strcmp(pingMode, 'Single step')
            
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Maximum</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
                                handles = sendrequest(app, handles, rString);
            
                                % Make ER60 active
                                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>OperationControl/OperationMode</paramName><paramValue>17</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                                handles = sendrequest(app, handles, rString);
                            end
                        end
            
                        % Send disconnect command
                        s = ['DIS' char(0) 'Name:' handles.settings.ER60Name ...
                            ';Password:' handles.settings.ER60Password char(0)];
                        fwrite(handles.ER60, s, 'char');
                    end
            
                    % Delete all instruments
                    if isfield(handles, 'ExtDepth')
                        if isvalid(handles.ExtDepth); delete(handles.ExtDepth); end
                    end
                    if isfield(handles, 'KSyncDepth')
                        if isvalid(handles.KSyncDepth); delete(handles.KSyncDepth); end
                    end
                    if isfield(handles, 'ER60')
                        if isvalid(handles.ER60); delete(handles.ER60); end
                    end
                    if isfield(handles, 'u1')
                        if isvalid(handles.u1); delete(handles.u1); end
                    end
            
                    % Close connection to passive noise measurements file
                    if isfield(handles, 'noiseFile')
                        fclose(handles.noiseFile);
                    end
                        
                    % Clear the status bar under the start button
                    set(handles.restartTest, 'String', '');
            
                    % Enable inputs
                    enableInputs(app, handles);
            
                    % Update GUI and save handles structure
                    drawnow nocallbacks
            %         drawnow expose update
                    guidata(handles.output, handles);
                end
            
            catch ME
            
                % Stop the timer which runs datareceived(). Only start it again once
                % connections have been re-established with the ER60.
                stop(handles.subcriptionTimer);
            
            %     % Once connections have been reset, disable currentlyRunning
            %     handles.currentlyRunning = 0;
            %     guidata(handles.output, handles);   % Update handles structure
            
                % If Error Logs directory doesn't exist, create it
                if ~isfolder(fullfile('C:\ProgramData\EAL', 'Error Logs'))
                    mkdir(fullfile('C:\ProgramData\EAL', 'Error Logs'))
                end
            
                % Save and display the error message.
                save(fullfile('C:\ProgramData\EAL', 'Error Logs', [char(datetime('now', Format='yyyyMMdd''T''HHmmss')) '_Error.mat']), 'ME')
                set(handles.restartTest, 'String', ME.message);
                disp(getReport(ME));
                drawnow nocallbacks
            %     drawnow expose update
                disp('Error occurred.  Resetting ER60.')
            
                % Reset the connection to the ER60.
                handles = resetER60(app, handles);
            
                % Once connections have been reset, disable currentlyRunning
                handles.currentlyRunning = 0;
                guidata(handles.output, handles);   % Update handles structure
                start(handles.subcriptionTimer);
            end
        end
        
        function handles = readSettings(app, handles)
            % Attempts to read a Settings.txt file various EAL parameters. If the files
            % exists, it loads and returns those parameters. If the file doesn't exist,
            % one is created with default parameters
            
            % If file doesn't exist, create it
            if exist(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'), 'file') ~= 2
                writeSettingsFile(app);
            end
            
            % Read text file
            data = fileread(fullfile('C:\ProgramData\EAL','EAL_Settings.txt'));
            
            handles.settings = regexp(data, ['IP Address =\s*(?<ER60IP>[^\s%]*).*' ...
                'Remote Port =\s*(?<RemotePort>[^\s%]*).*' ...
                'Name =\s*(?<ER60Name>[^\s%]*).*' ...
                'Password =\s*(?<ER60Password>[^\s%]*).*' ...
                '18 kHz =\s*(?<MaxLogRange18>[^\s%]*).*' ...
                '38 kHz =\s*(?<MaxLogRange38>[^\s%]*).*' ...
                '70 kHz =\s*(?<MaxLogRange70>[^\s%]*).*' ...
                '120 kHz =\s*(?<MaxLogRange120>[^\s%]*).*' ...
                '200 kHz =\s*(?<MaxLogRange200>[^\s%]*).*' ...
                '333 kHz =\s*(?<MaxLogRange333>[^\s%]*).*' ...
                'Processing buffer =\s*(?<ProcBuf>[^\s%]*).*' ...
                'Bottom Offset =\s*(?<BottomOffset>[^\s%]*).*' ...
                'Manual Ping Interval Override Time =\s*(?<ManPingTime>[^\s%]*).*' ...
                'External Depth Input Type =\s*(?<ExtDepthType>[^\s%]*).*' ...
                'External Depth Input IP =\s*(?<ExtDepthIP>[^\s%]*).*' ...
                'External Depth Input Port =\s*(?<ExtDepthPort>[^\s%]*).*' ...
                'External Depth Sound Speed =\s*(?<ExtDepthSV>[^\s%]*).*' ...
                'Detection Window Size =\s*(?<DetectionWindowSize>[^\s%]*).*' ...
                'Deep Bottom Ping Interval =\s*(?<DeepBotInt>[^\s%]*).*' ...
                'Deep Bottom Ping Range =\s*(?<DeepBotRange>[^\s%]*).*' ...
                'Passive Noise Interval =\s*(?<PassiveInt>[^\s%]*).*' ...
                'Number of passive pings =\s*(?<NumPassivePings>[^\s%]*).*' ...
                'Passive Noise Range =\s*(?<PassiveRange>[^\s%]*).*' ...
                'Removal Range =\s*(?<FBRemRange>[^\s%]*).*' ...
                '18 kHz Range =\s*(?<DispRange18>[^\s%]*).*' ...
                '38 kHz Range =\s*(?<DispRange38>[^\s%]*).*' ...
                '70 kHz Range =\s*(?<DispRange70>[^\s%]*).*' ...
                '120 kHz Range =\s*(?<DispRange120>[^\s%]*).*' ...
                '200 kHz Range =\s*(?<DispRange200>[^\s%]*).*' ...
                '333 kHz Range =\s*(?<DispRange333>[^\s%]*).*' ...
                'K-Sync IP =\s*(?<KSyncIP>[^\s%]*).*' ...
                'K-Sync UDP Port =\s*(?<KSyncUDPPort>[^\s%]*).*' ...
                'Non-ER60/EK80 Group Lengths =\s*(?<KSyncAdjust>[^\s%]*).*' ...
                ], 'names');
        end
        
        function str = readbetween(app, FirstPattern, LastPattern, Text)
            % Extracts the string located between FirstPattern and LastPattern in the
            % string Text
            
            pat = [FirstPattern '(.*)' LastPattern];
            temp = regexp(Text, pat, 'tokens');
            str = temp{:}{:};
        end
        

        function handles = resetER60(app, handles)
            
            handles.restartCount = handles.restartCount + 1;
            set(handles.restartTest, 'String', sprintf('ER60 reset %d times', handles.restartCount));
            fprintf('Resetting for the %d time\n', handles.restartCount);
            drawnow nocallbacks
            % drawnow expose update
            
            % Delete all instruments
            % delete(instrfindall)
            if isfield(handles, 'ExtDepth')
                if isvalid(handles.ExtDepth); delete(handles.ExtDepth); end
            end
            if isfield(handles, 'KSyncDepth')
                if isvalid(handles.KSyncDepth); delete(handles.KSyncDepth); end
            end
            if isfield(handles, 'ER60')
                if isvalid(handles.ER60); delete(handles.ER60); end
            end
            if isfield(handles, 'u1')
                if isvalid(handles.u1); delete(handles.u1); end
            end

            % Create the alarm signal
            fs = 44100;     % Sample rate (Hz)
            duration = 1;   % Duration of the sound (seconds)
            f1 = 500;       % Starting frequency of the siren (Hz)
            f2 = 1000;      % Ending frequency of the siren (Hz)
            t = 0:1/fs:duration;
            f = linspace(f1, f2, length(t));
            y = sin(2*pi*f.*t);
            
            % Reconnect to ER60
            flag = 1;
            while flag
            
                % Issue full drawnow command to flush event queue and process any
                % figure changes
                drawnow;

                % Make an alarm sound as long as a connection cannot be
                % made                
                sound(y, fs);
            
                disp('Trying to reconnect to the EK60/EK80...')
            
                % Only continue trying to connect if Start button is still pressed
                if get(handles.startButton, 'Value')
                    try
                        handles = connect2ER60(app, handles);
                        handles = subscribe2ER60(app, handles);
            
                        % If using K-Sync, create udp object for sending depth outputs
                        if get(handles.checkboxKSync, 'Value')
%                             handles.KSyncDepth = udp(handles.settings.KSyncIP, ...
%                                 str2double(handles.settings.KSyncUDPPort)); %#ok<TNMLP>
                            handles.KSyncDepth = udpport("byte", "IPV4");
                        end
            
                        % If using external depth sensor, create TCP/IP object
                        if ~isempty(handles.settings.ExtDepthIP)

                            % Create object depending on if it's TCP/IP or
                            % UDP
                            if strcmp(handles.settings.ExtDepthType, 'IP')
                                handles.ExtDepth = tcpclient(handles.settings.ExtDepthIP, str2double(handles.settings.ExtDepthPort));
                            elseif strcmp(handles.settings.ExtDepthType, 'UDP')
                                handles.ExtDepth = udpport("datagram", "IPV4", 'LocalPort', str2double(handles.settings.ExtDepthPort));
%                                 handles.ExtDepth.RemotePort = str2double(handles.settings.ExtDepthPort);
                                % configureMulticast(handles.ExtDepth, handles.settings.ExtDepthIP);
                            end
                        end
            
                        flag = 0;
                    catch ME
                        disp(getReport(ME))
            
                        if isfield(handles, 'ExtDepth')
                            if isvalid(handles.ExtDepth); delete(handles.ExtDepth); end
                        end
                        if isfield(handles, 'KSyncDepth')
                            if isvalid(handles.KSyncDepth); delete(handles.KSyncDepth); end
                        end
                        if isfield(handles, 'ER60')
                            if isvalid(handles.ER60); delete(handles.ER60); end
                        end
                        if isfield(handles, 'u1')
                            if isvalid(handles.u1); delete(handles.u1); end
                        end
            
                    end
                else
                    disp('Reconnection aborted.')
                    flag = 0;
                end
            end
        end
        
        function handles = sendAliveMessage(app, handles)
            
            % Send alive message to ER60 object
            write(handles.ER60, ['ALI' char(0) ...
                'ClientID:' handles.ER60CLIENTID ...
                ',SeqNo:' int2str(handles.ER60CLIENTSEQNO) ...
                char(0)], 'char');
        end
                
        function [handles, response] = sendrequest(app, handles, str)
            
            % Send request
            header = ['REQ' char(0)];                           % Header
            temp = [int2str(handles.ER60CLIENTSEQNO) ',1,1'];   % Sequence number
            msgcontrol = [temp repmat(char(0), 1, 22-length(temp))];    % Msg control
            s = [header msgcontrol str];                        % Put it all together
            
            % Send command
            flushinput(handles.ER60)
            write(handles.ER60, s, 'char');                     % Send to ER60
            
            temp = nan(1,3);
            flag = 1;
            while flag
                temp = [temp(2:3) double(read(handles.ER60, 1, 'int8'))];
            
                if isequal(temp, double('RES'))
                    flag = 0;
                elseif isequal(temp, double('RTR'))
                    write(handles.ER60, s, 'char');
                end
            end
            
            % Received request response
            read(handles.ER60, 27, 'char');
            response = read(handles.ER60,1400,"char");
            
            handles.ER60CLIENTSEQNO = handles.ER60CLIENTSEQNO+1;
        end
                
        function stopManualPI(app, ~, ~, obj)
            
            handles = guidata(obj);
            
            % Disable the inputs
            set(handles.setManualPI, 'Value', 0)
            set(handles.manualPI, 'Enable', 'off')
            set(handles.manualPIButton, 'Enable', 'off')
            set(handles.overrideTime, 'Enable', 'on')
            
            guidata(handles.output, handles);
        end
        
        function handles = subscribe2ER60(app, handles)
            
            try
            
                %% If subscription IDs exist, then unsubscribe and delete
                if isfield(handles, 'PowerID')
                    for i = 1:length(handles.freqs)
            
                        % Stop Power subscription
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>RemoteDataServer</targetComponent><method><Unsubscribe><subscriptionID>' num2str(handles.PowerID(i)) '</subscriptionID></Unsubscribe></method></request>' char(0)];
                        handles = sendrequest(app, handles, rString);
            
                        % Stop Sv subscription
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>RemoteDataServer</targetComponent><method><Unsubscribe><subscriptionID>' num2str(handles.SvID(i)) '</subscriptionID></Unsubscribe></method></request>' char(0)];
                        handles = sendrequest(app, handles, rString);
            
                        % Stop Angle subscription
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>RemoteDataServer</targetComponent><method><Unsubscribe><subscriptionID>' num2str(handles.AngleID(i)) '</subscriptionID></Unsubscribe></method></request>' char(0)];
                        handles = sendrequest(app, handles, rString);
                    end
            
                    handles = rmfield(handles, {'PowerID', 'SvID', 'AngleID'});
                end
            
                %% Subscribe to data for all ER60 frequencies
                temp = {'Sv', 'Power', 'Angle'};    % Subscription variables
            
                % Create new udp object for receiving data at the specified client dataport
                handles.u1 = udpport("byte", "IPV4");
                        
                % Loop through Sv, Power, and Angle subscriptions
                for i = 1:length(temp)
            
                    % Subscribe for each frequency
                    for j = 1:length(handles.freqs)
            
                        % Request string for Sv data
                        rString = ['<request><clientInfo><cid>' ...
                            handles.ER60CLIENTID ...
                            '</cid>' ...
                            '<rid>' int2str(handles.ER60RequestID) '</rid>' ...
                            '</clientInfo><type>invokeMethod</type>' ...
                            '<targetComponent>RemoteDataServer</targetComponent><method>' ...
                            '<Subscribe><requestedPort>' ...
                            int2str(handles.u1.LocalPort) ...
                            '</requestedPort><dataRequest>' ...
                            'SampleData,ChannelID=' handles.ER60transceiverID{j} ...
                            ',SampleDataType=' temp{i} ...
                            ',Range=' int2str(handles.currRange(j)) ...
                            ',RangeStart=0' ...
                            '</dataRequest></Subscribe></method></request>' char(0)];
            
                        try
                            [handles, response] = sendrequest(app, handles, rString);
                        catch ME
                            error('Error during subscription.  Stoppin EAL');
                        end
                        temp2 = readbetween(app, '<subscriptionID dt="3">','</subscriptionID>',response);
                        handles.([temp{i} 'ID'])(j) = str2double(temp2);
                    end
                end
            
                % Initialize Sv cell arrays to hold data for each frequency
                handles.Power = cell(length(handles.freqs), 1);
                handles.Sv = cell(length(handles.freqs), 1);
                handles.Angle = cell(length(handles.freqs), 1);
            
            catch ME
            
                % Delete all instruments
            %     delete(instrfindall)
                if isfield(handles, 'ExtDepth')
                    if isvalid(handles.ExtDepth); delete(handles.ExtDepth); end
                end
                if isfield(handles, 'KSyncDepth')
                    if isvalid(handles.KSyncDepth); delete(handles.KSyncDepth); end
                end
                if isfield(handles, 'ER60')
                    if isvalid(handles.ER60); delete(handles.ER60); end
                end
                if isfield(handles, 'u1')
                    if isvalid(handles.u1); delete(handles.u1); end
                end
            
            
                error(ME.message);
            end
        end
        
        function handles = updateSettings(app, handles)
            
            %% Get latest settings from text file
            handles = readSettings(app, handles);
            
            %% Update deep bottom info
            
            % If a deep bottom was collected
            if handles.currRange > handles.normRange
                handles.deepBottom = handles.depth;             % Update depth var.
                set(handles.buttonCheckBottomNow, 'Value', 0)   % Depress button
                handles.bottomCheckTimer = tic;                 % Start time over
                handles.emittingDeepPing = 0;   % Reset deep bottom collection flag
                drawnow nocallbacks
            %     drawnow expose update
            end
            
            % If the deep bottom timer exists (i.e. bottom was collected), calculate
            % remaining time
            if isfield(handles, 'bottomCheckTimer')
                remTime = round(60*str2double(handles.settings.DeepBotInt) - toc(handles.bottomCheckTimer));
            
                % If periodically checking deep bottoms, display remaining time
                if get(handles.checkboxCheckDeepBottom, 'Value')
                    if remTime > 60	% If more than a minute left, display in minutes
                        set(handles.nextBottomCheck, 'String', ...
                            sprintf('%0.0f minutes', ceil(remTime/60)))
                    elseif remTime > 0 	% If between 0 and 60, display in seconds
                        set(handles.nextBottomCheck, 'String', ...
                            sprintf('%0.0f seconds', remTime))
                    else                % Otherwise it's negative, so collect ping now
                        set(handles.nextBottomCheck, 'String', 'Detecting now...')
                    end
                else
                    set(handles.nextBottomCheck, 'String', '')
                end
            else
                remTime = NaN;
            end
            
            %% Update GUI with bottom detection results
            % If a depth was directly observed, either by an external depth
            % sensor or through subscription data, then that value is
            % stored in handles.depth. If no depth was found, then try to
            % estimate one by either via a currDepth.txt file or a
            % bathymetry estimator, in that order. If none of those work,
            % then just assume we have no idea what the depth.
            
            % If depth was found
            if ~isnan(handles.depth)
                set(handles.Depth, 'String', sprintf('%.02f', handles.depth));
                set(handles.Depth, 'BackgroundColor', [0.831 0.816 0.784]);
                set(handles.Depth, 'ForegroundColor', [0 0 0]);
                estDepth = handles.depth;
            
            % Else, see if an estimate can be obtained
            else
            
                % First see if a recent currDepth.txt file exists. If so,
                % try to read it and assign that depth
                fid = fopen('currDepth.txt', 'r');
                if ~isequal(fid, -1)
                    disp('currDepth file exists!')
            
                    C = textscan(fid, '%D%f%f%f', 'Delimiter', ',');
                    fclose(fid);
            
                    % If data exists for all variables (e.g., timestamp,
                    % position, and depth), continue parsing
                    if all(~cellfun(@isempty, C))

                        % Compute time difference, in seconds, between now
                        % and when the estimate was obtained
                        timeDiff = abs(seconds(C{1} - datetime('now')));

                        % If the time difference is less than 10 seconds,
                        % assign to the estimated depth variable and update
                        % the GUI
                        if timeDiff <= 60 && ~isnan(C{4})
                            estDepth = C{4};
                            set(handles.Depth, 'String', sprintf('%.02f', estDepth));
                            set(handles.Depth, 'BackgroundColor', [66 226 244]./255);
                            set(handles.Depth, 'ForegroundColor', [0.502 0.502 0.502]);
                        end
                    end
                end
            
                % If unable to get depth from a currDepth file and an
                % estimator function exists from a bathymetry database, try
                % to use that
                if ~exist("estDepth", "var") && isfield(handles, 'estFunc')
            
                    % Get longitude
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OwnShip/Longitude</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    long = str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Get latitude
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OwnShip/Latitude</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    lat = str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % If trying to remove false bottoms, but location is outside the bathy
                    % grid, alert user
                    if get(handles.fixFB, 'Value') && ...
                            (~(long >= min(handles.estFunc.F.Points(:,1)) && long <= max(handles.estFunc.F.Points(:,1))) || ...
                            ~(lat >= min(handles.estFunc.F.Points(:,2)) && lat <= max(handles.estFunc.F.Points(:,2))))
                        play(handles.Audio);
                        msgbox('Current location is outside of bathymetry grid', ...
                            'Outside Bathymetry Grid', 'warn', 'replace');
                        % estDepth = NaN;
            
                    % Otherwise, calculate estimated depth
                    else
                        estDepth = -1*handles.estFunc.F(long, lat);
                        set(handles.Depth, 'String', sprintf('%.02f', estDepth));
                        set(handles.Depth, 'BackgroundColor', [0.831 0.816 0.784]);
                        set(handles.Depth, 'ForegroundColor', [0.502 0.502 0.502]);
                    end
            
                % Else, if a recent deep bottom detection exists, assign
                % that to the estimated depth variable
                elseif remTime > 0
                    estDepth = handles.deepBottom;
                    set(handles.Depth, 'String', sprintf('%.02f', handles.deepBottom));
                    set(handles.Depth, 'BackgroundColor', [0.831 0.816 0.784]);
                    set(handles.Depth, 'ForegroundColor', [0.502 0.502 0.502]);
            
                % Else, change the GUI box to yellow indicating that depth is unknown
                else
                    disp('No bottom estimate available!')
                    set(handles.Depth, 'BackgroundColor', [1 1 0]);
                    set(handles.Depth, 'ForegroundColor', [0.502 0.502 0.502]);
                end
            end
            
            %% Update plots
            set(handles.h(1), 'YData', handles.lastDepths)
            set(handles.h(2), 'YData', handles.lastSlopes)
            set(handles.h(3), 'YData', handles.lastDZHs)
            set(handles.h(4), 'YData', handles.lastRoughnesses)
            
            % Set x-axis limits
            set(handles.axesDepth, 'XLim', [0 100]);
            set(handles.axesSlope, 'XLim', [0 100]);
            set(handles.axesDZH, 'XLim', [0 100]);
            set(handles.axesRoughness, 'XLim', [0 100]);
            
            %% Calculate new logging range and detection window
            % If a depth was directly measured (i.e., external sensor or
            % subscription data), or if an estimate depth is available and
            % deeper than the max logging range, then set the logging range
            % and detection window directly based on that. However, if an
            % estimate depth is available but shallower than the max
            % logging range, default to using the max logging range
            
            % Get latest maximum logging ranges
            for i = 1:length(handles.freqs)
                handles.normRange(i) = str2double(eval(sprintf('handles.settings.MaxLogRange%d', handles.freqs(i)/1e3)));
            end
            
            % Get latest processing buffer time
            procTime = str2double(handles.settings.ProcBuf);
            
            % Update the normal ping rate based on max logging range
            handles.normRate = round(100*(procTime + (max(handles.normRange) * 2/handles.c)))/100;

            % If EAL was just started and hasn't detected a depth yet, let
            % it try a few times before trying to use an estimated depth
            if isnan(handles.depth) && handles.firstPing

                % Update the logging range in case user changed the max range
                loggingRange = handles.normRange;

                % If less than 10 missed detections, update detection window in
                % case a new max range has been set
                if handles.firstPingCount < 3
                    handles.RangeWindow = [0; max(handles.normRange)];   % Detection window
                    handles.firstPingCount = handles.firstPingCount + 1;

                % If it's missed some number of pings, assume depth is beyond the
                % max logging range and update detection window accordingly
                else
                    handles.firstPing = 0;
                    handles.RangeWindow = [max([0 max(handles.normRange)-str2double(handles.settings.DetectionWindowSize)]); max(handles.normRange)];
                end

            % If a depth was directly observed or if an estimate exists
            elseif ~isnan(handles.depth) || exist("estDepth", "var")

                if handles.depth < 50
                    loggingRange = min([handles.normRange repmat(ceil(handles.depth + 50), length(handles.freqs), 1)], [], 2);
                elseif handles.depth >= 50 && handles.depth < 350
                    loggingRange = min([handles.normRange repmat(ceil(handles.depth*2 + 75), length(handles.freqs), 1)], [], 2);
                elseif handles.depth >= 350 && handles.depth < 750
                    loggingRange = min([handles.normRange repmat(ceil(handles.depth + 150), length(handles.freqs), 1)], [], 2);
                elseif estDepth >= 750 && estDepth < 1500
                    loggingRange = min([handles.normRange repmat(650, length(handles.freqs), 1)], [], 2);
                elseif estDepth >= 1500
                    loggingRange = min([handles.normRange repmat(500, length(handles.freqs), 1)], [], 2);

                % If direct depth doesn't exist and estDepth is < 750 m,
                % then set logging range to max. This can happen for
                % example when it's 800 m and we're only logging to 650 m,
                % in which case we won't directly measure the seabed once
                % it comes above 750 m. So will need to rely on estimate to
                % determine when to increase the logging range again
                else
                    loggingRange = min([handles.normRange repmat(750, length(handles.freqs), 1)], [], 2);
                end
                
                % If a depth is directly measured (e.g., < 750 m), then the
                % range window should be centered around that measured
                % depth.
                if ~isnan(handles.depth)
                    handles.RangeWindow = [floor(max([0 handles.depth-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];

                % Else, if an estimate exists and is less than the max
                % logging range, use that for window
                elseif exist("estDepth", "var")
                    if estDepth <= max(ceil(loggingRange))
                        handles.RangeWindow = [floor(max([0 estDepth-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
                    else
                        handles.RangeWindow = [floor(max([0 max(ceil(loggingRange))-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
                    end
                
                % Else set the detection window to near the logging range
                else
                    handles.RangeWindow = [floor(max([0 max(ceil(loggingRange))-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
                end

            % If neither exists, then simply set the logging range and
            % detection window to the max logging range
            else            
                loggingRange = min([handles.normRange repmat(750, length(handles.freqs), 1)], [], 2);
                handles.RangeWindow = [floor(max([0 max(ceil(loggingRange))-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
            end


            % % If depth is not found
            % if isnan(handles.depth)
            % 
            %     % If EAL was just started and hasn't detected a depth yet
            %     if handles.firstPing
            % 
            %         % Update the logging range in case user changed the max range
            %         loggingRange = handles.normRange;
            % 
            %         % If less than 10 missed detections, update detection window in
            %         % case a new max range has been set
            %         if handles.firstPingCount < 3
            %             handles.RangeWindow = [0; max(handles.normRange)];   % Detection window
            %             handles.firstPingCount = handles.firstPingCount + 1;
            % 
            %         % If it's missed some number of pings, assume depth is beyond the
            %         % max logging range and update detection window accordingly
            %         else
            %             handles.firstPing = 0;
            %             handles.RangeWindow = [max([0 max(handles.normRange)-str2double(handles.settings.DetectionWindowSize)]); max(handles.normRange)];
            %         end
            % 
            %     % If bottom is below the max logging range. This condition is met if
            %     % the detection window either (1) covers the entire echogram range, (2)
            %     % is looking at the bottom of the echogram range, or (3) a deep bottom
            %     % ping was performed.
            %     elseif  isequal(handles.RangeWindow, [0; max(handles.normRange)]) || ...
            %             handles.RangeWindow(1) >= max(handles.normRange) - 2*str2double(handles.settings.DetectionWindowSize) || ...
            %             handles.RangeWindow(2) > max(handles.normRange)
            %         %             isequal(handles.RangeWindow, [handles.normRange-str2double(handles.settings.DetectionWindowSize); handles.normRange]) || ...
            % 
            %         % If an estimated depth exists and is deeper than the
            %         % max logging range, set the depth equal to it and set
            %         % the RangeWindow to look at the bottom of the echogram
            %         if ~isnan(estDepth) && estDepth > max(handles.normRange)
            %             handles.depth = estDepth;
            %             handles.RangeWindow = [max([0 max(handles.normRange)-str2double(handles.settings.DetectionWindowSize)]); max(handles.normRange)];
            %         end
            % 
            %         % If the bottom check timer exists, check to see if enough time has
            %         % elapsed for a new deep bottom ping.
            %         if isfield(handles, 'bottomCheckTimer')
            %             flag = toc(handles.bottomCheckTimer) >= 60*str2double(handles.settings.DeepBotInt);
            % 
            %         % If the timer didn't exist, then set flag to 1 so that a deep
            %         % bottom is still collected if the checkbox is checked.
            %         else
            %             flag = 1;
            %         end
            % 
            %         % If an estimated depth wasn't obtained, and the "Check
            %         % now" button is pressed OR the check deep bottom
            %         % checkbox is checked and timer has elapsed or
            %         % deepBottom is 0, then collect a deep bottom ping
            %         if isnan(handles.depth) && ...
            %                 (get(handles.buttonCheckBottomNow, 'Value') || ...
            %                 (get(handles.checkboxCheckDeepBottom, 'Value') && flag))
            % 
            %                 % Put ER60 in single step mode to prevent more pings from
            %                 % causing false bottom in deep bottom data
            %                 rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/PingMode</paramName><paramValue>Single step</paramValue><paramType>8</paramType></SetParameter></method></request>' char(0)];
            %                 handles = sendrequest(app, handles, rString);
            % 
            %                 % Set flag so that we know to emit a ping after the
            %                 % subscription parameters have been set
            %                 handles.emittingDeepPing = 1;
            % 
            %                 % Start timer so we know when to emit the deep ping
            %                 handles.deepPingTimer = tic;
            % 
            %                 loggingRange = str2double(handles.settings.DeepBotRange);
            %                 handles.RangeWindow = [handles.normRange; loggingRange];    % Set detection window to entire logging range
            % 
            %         % Otherwise, we have no idea what the depth is, or an
            %         % estimate that's beyond the max logging range, so set
            %         % logging range to maximum logging range and the
            %         % detection window to look near the max logging range.
            %         else
            %             handles.RangeWindow = [max([0 max(handles.normRange)-str2double(handles.settings.DetectionWindowSize)]); max(handles.normRange)];
            % 
            %             if handles.depth < 50
            %                 loggingRange = min([handles.normRange repmat(ceil(handles.depth + 50), length(handles.freqs), 1)], [], 2);
            %             elseif handles.depth >= 50 && handles.depth < 350
            %                 loggingRange = min([handles.normRange repmat(ceil(handles.depth*2 + 75), length(handles.freqs), 1)], [], 2);
            %             elseif handles.depth >= 350 && handles.depth < 750
            %                 loggingRange = min([handles.normRange repmat(ceil(handles.depth + 150), length(handles.freqs), 1)], [], 2);
            %             elseif handles.depth >= 750 && handles.depth < 1500
            %                 loggingRange = min([handles.normRange repmat(650, length(handles.freqs), 1)], [], 2);
            %             else
            %                 loggingRange = min([handles.normRange repmat(500, length(handles.freqs), 1)], [], 2);
            %             end
            % 
            %             % loggingRange = handles.normRange;
            %         end
            % 
            %     % Otherwise, the detection window is somewhere in the
            %     % mid-water column, so add 15 m to both ends and continue to
            %     % look for the bottom
            %     else
            % 
            %         % Add 15 m to each end of RangeWindow
            %         handles.RangeWindow = [max([0 handles.RangeWindow(1)-15]);
            %             min([max(handles.normRange) handles.RangeWindow(2)+15])];
            % 
            %         loggingRange = min([handles.normRange handles.currRange+15],[],2);
            %     end
            % 
            % % A depth was found
            % else
            %     % If EAL was just started, set flag to 0 in case it's missed next time
            %     if handles.firstPing; handles.firstPing = 0; end
            % 
            %     % Calculate new logging range. For the 2025 IWCPS survey,
            %     % the range will be calculated as follows:
            %     %   0 - 50 m = Bottom + 50
            %     %   50-350 m = 2*Bottom + 75
            %     %   350 - 600 m = Bottom + 150
            %     %   600 - 750 m = 750 m
            %     %   750 - 1500 m = 650 m
            %     %   > 1500 m = 500 m
            %     if handles.depth < 50
            %         loggingRange = min([handles.normRange repmat(ceil(handles.depth + 50), length(handles.freqs), 1)], [], 2);
            %     elseif handles.depth >= 50 && handles.depth < 350
            %         loggingRange = min([handles.normRange repmat(ceil(handles.depth*2 + 75), length(handles.freqs), 1)], [], 2);
            %     elseif handles.depth >= 350 && handles.depth < 750
            %         loggingRange = min([handles.normRange repmat(ceil(handles.depth + 150), length(handles.freqs), 1)], [], 2);
            %     elseif handles.depth >= 750 && handles.depth < 1500
            %         loggingRange = min([handles.normRange repmat(650, length(handles.freqs), 1)], [], 2);
            %     else
            %         loggingRange = min([handles.normRange repmat(500, length(handles.freqs), 1)], [], 2);
            %     end
            % 
            %     % Set new detection window
            %     if handles.depth > max(handles.normRange)    % If deep bottom detection
            %         handles.RangeWindow = [floor(max([0 max(handles.normRange)-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
            %     else    % If normal detection
            %         handles.RangeWindow = [floor(max([0 handles.depth-str2double(handles.settings.DetectionWindowSize)])); max(ceil(loggingRange))];
            %     end
            % end
                        
            % Set the logging range based on if it's using single or
            % individual logging ranges. First obtain variable indicating
            % if the EK80 is using individual logging ranges for each
            % transducer
            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/IndividualChannelRecordingRange</paramName><time>0</time></GetParameter></method></request>' char(0)];
            [handles, response] = sendrequest(app, handles, rString);
            IndChannelFlag = str2double(readbetween(app, '<value dt="3">','</value>',response));
        
            % If flag is 1, then EK80 is set to record to individual ranges for
            % each transducer. So will need to cycle through each WBT and set the
            % sample ranges
            if IndChannelFlag
        
                % Cycle through frequencies
                for i = 1:length(handles.freqs)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/' handles.ER60transceiverID{i} '_ES/Range</paramName><paramValue>' int2str(loggingRange(i)) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                    handles = sendrequest(app, handles, rString);
                end
        
                % Update in GUI
                set(handles.lRange, 'String', max(loggingRange))
        
            % Otherwise, only need to set the single logging range, in which case
            % we'll use the maximum of the logging ranges (should ideally all be
            % the same if the user wants that option).
            else
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>SounderStorageManager/SampleRange</paramName><paramValue>' int2str(max(loggingRange)) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString);
        
                % Update in GUI
                set(handles.lRange, 'String', max(loggingRange))
            end
            
            % Display ping time in GUI
            set(handles.pingTime, 'String', string(datetime(handles.currTime, 'Format', 'HH:mm:ss ddMMMyyyy')))
            
            % Display detection results in command window
            fprintf('Detection window = %d to %d m\n', handles.RangeWindow(1), handles.RangeWindow(2));
            fprintf('Logging range = %d\n', max(loggingRange));
                        
            %% Calculate ping interval
            
            % If using manual ping interval override
            if get(handles.setManualPI, 'Value')
                PI = str2double(get(handles.manualPI, 'String'));   % Get PI
                if ~isfield(handles, 'savedPI') % Save PI if it doesn't exist
                    handles.savedPI = PI;
                elseif ~isequal(handles.savedPI, PI)	% If PI changed, restart timer
                    handles.savedPI = PI;
                    stop(handles.setPITimer)
                    start(handles.setPITimer)
                end
            else
            
                % Calculate the minimum PI given the logging range
                minPI = procTime + (max(loggingRange) * 2/handles.c);
            
                % If using the K-Sync, the minimum ping interval must take into account
                % the time spent in groups not containing the ER60/EK80
                if get(handles.checkboxKSync, 'Value')
                    minPI = minPI + str2double(handles.settings.KSyncAdjust);
                end
            
                % If wanting to remove false bottoms
                if get(handles.fixFB, 'Value')
            
                    % If depth was found, then use it
                    if ~isnan(handles.depth)
                        depth = handles.depth;
                    elseif exist("estDepth", "var")
                        depth = estDepth;
            
                    % If depth was not found, then see if any recent detections exist
                    % from a deep bottom ping
                    else
            
                        % Periodically checking for deep bottom?
                        if get(handles.checkboxCheckDeepBottom, 'Value') && isfield(handles, 'deepBottom')
                            depth = handles.deepBottom;
                        else
            
                            % Does a deep bottom exist from a recent detection?
                            if remTime > 0
                                depth = handles.deepBottom;
            
                            % Otherwise, no way of knowing depth so set to NaN
                            else
                                depth = NaN;
                            end
                        end
                    end
            
                    % Get latest false bottom removal range
                    remRange = str2double(handles.settings.FBRemRange);
            
                    % Create flag variable indicating if false bottom is corrected
                    FBflag = 0;
            
                    % If depth is less than the ping interval
                    if depth <= minPI * handles.c / 2
            
                        % Calculate where a false bottom would occur if the maximum
                        % ping rate were used.
                        N = ceil(minPI*handles.c/(2*depth));
                        RFB = N*depth - (minPI*handles.c/2);	% Depth of FB
            
                        % If a false bottom would occur within the removal range, then
                        % correct for it.
                        fprintf('At a depth of %.0f and a minPI of %.2f, a false bottom would occur at %.0f m after %d echoes\n', depth, minPI, RFB, N)
                        if RFB < min([remRange depth]) && N <= 2
                        % if RFB < remRange
                            PI = 2*N*depth/handles.c;
                            FBflag = 1;
            
                        % Otherwise use maximum ping rate.
                        else
                            PI = minPI;
                        end
            
                    % If depth is greater than the ping interval
                    else
            
                        buffer = 0;
            
                        % Calculate R_AS using R_S
                        N = floor(depth*2/(minPI*handles.c));
                        RFB1 = depth - N*minPI*handles.c/2;
            
                        % Calculate R_AS using R_S + buffer
                        N = floor((depth+buffer)*2/(minPI*handles.c));
                        RFB2 = (depth+buffer) - N*minPI*handles.c/2;
            
                        % Calculate R_AS using R_S - buffer
                        N = floor((depth-buffer)*2/(minPI*handles.c));
                        RFB3 = (depth-buffer) - N*minPI*handles.c/2;

                        fprintf('At a depth of %.0f and a minPI of %.2f, a false bottom could occur between %.0f m and %.0f m after %d echoes\n', depth, minPI, RFB2, RFB3, N)
            
                        % If R_AS <= R_L using R_S or R_S + buffer
                        if RFB1 <= min([remRange max(loggingRange)]) || RFB2 <=  min([remRange max(loggingRange)])
                            N = floor((depth+buffer)*2/(minPI*handles.c));
                            PI = 2*(depth+buffer)/(N*handles.c);
                            FBflag = 1;
            
                        % Else, if RS <= R_L using R_S and R_S - buffer
                        elseif RFB1 > min([remRange max(loggingRange)]) && RFB3 <= min([remRange max(loggingRange)])
                            N = floor(depth*2/(minPI*handles.c));
                            PI = 2*depth/(N*handles.c);
                            FBflag = 1;
            
                        % Otherwise, don't need to worry about false bottom, so use the
                        % maximum ping rate
                        else
                            PI = minPI;
                        end
                    end
            
                    % If false bottom would occur within the removal range, then update
                    % status on GUI.
                    if FBflag
            
                        % Update GUI
                        set(handles.FBCorrected, 'String', 'False bottom corrected!');
                        set(handles.FBCorrected, 'BackgroundColor', [0; 1; 0]);
            
                    % Otherwise, use maximum ping rate
                    else
                        PI = minPI;
            
                        % Update GUI
                        set(handles.FBCorrected, 'String', 'No correction needed');
                        set(handles.FBCorrected, 'BackgroundColor', [0.941; 0.941; 0.941]);
                    end
                else	% If not wanting to remove false bottom, use minimum PI
                    PI = minPI;
                end
            end
            
            % Set the ping interval
            rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>AcousticDeviceSynchroniser/Interval</paramName><paramValue>' int2str(PI*1000) '</paramValue><paramType>3</paramType></SetParameter></method></request>' char(0)];
            handles = sendrequest(app, handles, rString);
            
            % Update info on GUI and Command Window
            fprintf('Ping interval = %0.2f\n', PI);
            fprintf('Ping rate = %0.2f\n', 1/PI);
            set(handles.pInterval, 'String', sprintf('%.02f', PI))
            
            %% If using with K-Sync, send depth NMEA string
            if get(handles.checkboxKSync, 'Value')
            
                % If using the K-Sync, we must take remove the time spent in the other
                % trigger groups not containing the ER60/EK80.
                PI = PI - str2double(handles.settings.KSyncAdjust);
            
                depthM = PI * 750;
                NMEA = sprintf('$SDDPT,%.2f,0.00,*', depthM);

                fprintf('Depth to K-Sync = %.2f\n', depthM)
            
                % Calculate checksum
                temp = double(NMEA(2:end-1));
                checksum = 0;
                for j = 1:length(temp)
                    checksum = bitxor(checksum, temp(j));
                    checksum = uint16(checksum);
                end
                checksum = double(checksum);
                checksum = dec2hex(checksum);
                if length(checksum) == 1; checksum = strcat('0',checksum); end
            
                % Send depth to K-Sync
                % fopen(handles.KSyncDepth);
                writeline(handles.KSyncDepth, ...
                        sprintf('%s%s', NMEA, checksum), ...
                        handles.settings.KSyncIP, str2double(handles.settings.KSyncUDPPort))
                % writeline(handles.KSyncDepth, ...
                %         sprintf('%s%s%s', NMEA, checksum, [char(13) newline]), ...
                %         handles.settings.KSyncIP, str2double(handles.settings.KSyncUDPPort))
                % fclose(handles.KSyncDepth);
            end
            
            %% Update ER60 with new parameters
            
            % Update the frequency specific settings
            for i = 1:length(handles.freqs)
            
                % Get display range for that frequency
                dispRange = str2double(handles.settings.(sprintf('DispRange%d', handles.freqs(i)/1e3)));

                % If checkbox is selected and display range is less than logging range,
                % change it to the set display range
                if get(handles.editDispRange, 'Value') && dispRange <= loggingRange(i)
                    rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/RangeStart</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' int2str(dispRange) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                else
                    rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/RangeStart</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' int2str(loggingRange(i)) '</paramValue><paramApplyToAll>0</paramApplyToAll><paramType>5</paramType></SetParameter></method></request>' char(0)];
                end
                handles = sendrequest(app, handles, rString1);
                handles = sendrequest(app, handles, rString2);
            
                % Update bottom detection ranges
                rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/UpperDetectorLimit</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>ProcessingMgr/' handles.ER60transceiverID{i} '_ES/ChannelProcessingCommon/LowerDetectorLimit</paramName><paramValue>' int2str(handles.RangeWindow(2)) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                handles = sendrequest(app, handles, rString1);
                handles = sendrequest(app, handles, rString2);
            
                % If logging range has changed, then update subscription
                if ~isequal(loggingRange(i), handles.currRange(i))
            
                    temp = {'Power', 'Sv', 'Angle'};
                    for j = 1:length(temp)
                        % Update the Power data subscription range
                        rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID ...
                            '</cid>' ...
                            '<rid>' int2str(handles.ER60RequestID) '</rid>' ...
                            '</clientInfo><type>invokeMethod</type>' ...
                            '<targetComponent>RemoteDataServer</targetComponent>' ...
                            '<method><ChangeSubscription>' ...
                            '<subscriptionID>' num2str(handles.([temp{j} 'ID'])(i)) '</subscriptionID>' ...
                            '<dataRequest>' ...
                            'SampleData' ...
                            ',ChannelID=' handles.ER60transceiverID{i} ...
                            ',SampleDataType=' temp{j} ...
                            ',Range=' int2str(loggingRange(i)) ...
                            ',RangeStart=0' ...
                            '</dataRequest></ChangeSubscription></method></request>' char(0)];
                        handles = sendrequest(app, handles, rString);
                    end
                end
            end
            handles.currRange = loggingRange;
        end
        
%         function writeSettingsFile(app)
%             
%             % Create file and get identifier
%             fid = fopen('Settings.txt', 'w');
%             
%             % Write file
%             fprintf(fid, '// ER60/EK80 Remoting Settings\r\n');
%             fprintf(fid, 'IP Address = 192.168.123.105\t%% Local IP address defined in ER60/EK80 Remoting dialog\r\n');
%             fprintf(fid, 'Remote Port = 37655\t%% Local Port defined in ER60 Remoting dialog\r\n');
%             fprintf(fid, 'Name = Simrad\t%% Username defined in ER60/EK80 Users and Passwords dialog\r\n');
%             fprintf(fid, 'Password =\t%% Password for the above username\r\n\r\n');
%             
%             fprintf(fid, '// Max Logging Range Settings\r\n');
%             fprintf(fid, '18 kHz = 750\t%% Maximum range to log 18 kHz data (m)\r\n');
%             fprintf(fid, '38 kHz = 750\t%% Maximum range to log 38 kHz data (m)\r\n');
%             fprintf(fid, '70 kHz = 750\t%% Maximum range to log 70 kHz data (m)\r\n');
%             fprintf(fid, '120 kHz = 750\t%% Maximum range to log 120 kHz data (m)\r\n');
%             fprintf(fid, '200 kHz = 750\t%% Maximum range to log 200 kHz data (m)\r\n');
%             fprintf(fid, '333 kHz = 750\t%% Maximum range to log 333 kHz data (m)\r\n\r\n');
%             
%             fprintf(fid, '// Ping Settings\r\n');
%             fprintf(fid, 'Processing buffer = 0.16\t%% Time buffer to account for ER60 processing (s)\r\n');
%             fprintf(fid, 'Bottom Offset = 60\t%% Logging range is bottom depth plus this offset (m)\r\n');
%             fprintf(fid, 'Manual Ping Interval Override Time = 5\t%% Override time to use manual ping interval (min)\r\n\r\n');
%             
%             fprintf(fid, '// Bottom Detection Settings\r\n');
%             fprintf(fid, 'External Depth Input IP = \t%% Enter IP of system supplying DBT or DBS inputs, blank if none\r\n');
%             fprintf(fid, 'Detection Window Size = 15\t%% Detection window is last depth +- this range (m)\r\n\r\n');
%             
%             fprintf(fid, '// Deep Bottom Ping Settings\r\n');
%             fprintf(fid, 'Deep Bottom Ping Interval = 10\t%% Time between deep bottom detections (min)\r\n');
%             fprintf(fid, 'Deep Bottom Ping Range = 5000\t%% Range (m) of deep bottom pings, if no bathymetry file\r\n\r\n');
%             
%             fprintf(fid, '// Noise Measurement Settings\r\n');
%             fprintf(fid, 'Passive Noise Interval = 30\t%% Time between passive noise measurements (min)\r\n');
%             fprintf(fid, 'Number of passive pings = 3\t%% Number of passive pings to collect for noise measurement\r\n');
%             fprintf(fid, 'Passive Noise Range = 100\t%% Range (m) of passive noise pings\r\n\r\n');
%             
%             fprintf(fid, '// False Bottom Removal Settings\r\n');
%             fprintf(fid, 'Removal Range = 250\t%% Range above which false bottoms will be removed (m)\r\n\r\n');
%             
%             fprintf(fid, '// Display Settings\r\n');
%             fprintf(fid, '18 kHz Range = 750\t%% Display range of 18 kHz echogram (m)\r\n');
%             fprintf(fid, '38 kHz Range = 750\t%% Display range of 38 kHz echogram (m)\r\n');
%             fprintf(fid, '70 kHz Range = 750\t%% Display range of 70 kHz echogram (m)\r\n');
%             fprintf(fid, '120 kHz Range = 750\t%% Display range of 120 kHz echogram (m)\r\n');
%             fprintf(fid, '200 kHz Range = 750\t%% Display range of 200 kHz echogram (m)\r\n');
%             fprintf(fid, '333 kHz Range = 750\t%% Display range of 333 kHz echogram (m)\r\n\r\n');
%             
%             fprintf(fid, '// K-Sync Settings\r\n');
%             fprintf(fid, 'K-Sync IP = 157.237.60.169\t%% IP Address of K-Sync Synchronization Unit\r\n');
%             fprintf(fid, 'K-Sync UDP Port = 4502\t%% UDP port to sending K-Sync data (typically not changed)\r\n');
%             fprintf(fid, 'Non-ER60/EK80 Group Lengths = 0.02\t%% Time spent in groups not containing the ER60/EK80 (s)');
%                         
%             fclose(fid);
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EAL_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); 
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EAL (see VARARGIN)
            
            % Choose default command line output for EAL
            handles.output = hObject;
            
            WarnWave = [sin(1:.6:400), sin(1:.7:400), sin(1:.4:400)];
            handles.Audio = audioplayer(WarnWave, 22050);
            
            % Get filter coefficients used for smoothing out Sv and Pr data for bottom
            % detection
            [handles.B, handles.A] = butter(2, .05);    % Lowpass filter coefficients
            
            %% Set figure axes properties
            
            % Remove the axis tick marks
            set(handles.axesDepth, 'XTick', [], 'YTick', []);
            set(handles.axesSlope, 'XTick', [], 'YTick', []);
            set(handles.axesDZH, 'XTick', [], 'YTick', []);
            set(handles.axesRoughness, 'XTick', [], 'YTick', []);
            
            % Create y-axis labels
            ylabel(handles.axesDepth, 'Depth')
            ylabel(handles.axesSlope, 'Slope')
            ylabel(handles.axesDZH, 'h_{ADZ}')
            ylabel(handles.axesRoughness, 'Roughness')
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: bathyFileBrowse
        function bathyFileBrowse_Callback(app, event)
            % --- Executes on button press in bathyFileBrowse.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to bathyFileBrowse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Prompt user to select .mat file storing bathymetry estimator
            [filename, pathname] = uigetfile('*.mat', 'Pick a MAT file with bathymetry data');
            if ~isequal(filename,0) && ~isequal(pathname,0)
                set(handles.bathyFile, 'String', fullfile(pathname, filename))
                set(handles.bathyLoadStatus, 'String', 'File not yet loaded');
                set(handles.bathyLoadStatus, 'BackgroundColor', [1; 1; 0]);
                drawnow nocallbacks
            %     drawnow expose update
            end
        end

        % Button pushed function: buttonSettings
        function buttonSettings_Callback(app, event)
            % --- Executes on button press in buttonSettings.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to buttonSettings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % If Settings.txt doesn't exist, create it
            if exist(fullfile('C:\ProgramData\EAL', 'EAL_Settings.txt'), 'file') ~= 2
                EAL_App.writeSettingsFile;
            end
            
            % Open Settings file in Notepad
            system('start "" "C:\ProgramData\EAL\EAL_Settings.txt"');
        end

        % Value changed function: checkboxCheckDeepBottom
        function checkboxCheckDeepBottom_Callback(app, event)
            % --- Executes on button press in checkboxCheckDeepBottom.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            

            % hObject    handle to checkboxCheckDeepBottom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxCheckDeepBottom
            
            % If the box is checked, then start time and enable first check
            if get(handles.checkboxCheckDeepBottom, 'Value')
                handles.deepBottom = 0;
            else
                set(handles.nextBottomCheck, 'String', '')
            end
            guidata(handles.output, handles);
        end

        % Value changed function: fixFB
        function fixFB_Callback(app, event)
            % --- Executes on button press in fixFB.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to fixFB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fixFB
            
            % If checkbox is pressed and program is not running, then enable inputs
            if get(handles.fixFB, 'Value')
                set(handles.FBCorrected, 'Enable', 'inactive');
                set(handles.FBCorrected, 'BackgroundColor', [0.941; 0.941; 0.941]);
            
                % If program isn't running, then enable bathymetry file inputs
                if isequal(get(handles.startButton, 'Value'), 0)
                    set(handles.bathyFile, 'Enable', 'on');
                    set(handles.bathyFileBrowse, 'Enable', 'on');
                    set(handles.loadBathy, 'Enable', 'on');
                    set(handles.bathyLoadStatus, 'Enable', 'inactive');
            
                    % If bathy estimator doesn't exist, make yellow
                    if ~isfield(handles, 'estFunc')
                        set(handles.bathyLoadStatus, 'BackgroundColor', [1; 1; 0]);
                    end
                end
            
                % Otherwise it's not removing false bottoms so everything can be disabled
            else
                set(handles.bathyFile, 'Enable', 'off');
                set(handles.bathyFileBrowse, 'Enable', 'off');
                set(handles.loadBathy, 'Enable', 'off');
                set(handles.FBCorrected, 'Enable', 'off');
                set(handles.bathyLoadStatus, 'Enable', 'off');
            end
        end

        % Button pushed function: loadBathy
        function loadBathy_Callback(app, event)
            % --- Executes on button press in loadBathy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to loadBathy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get file name and parts
            file = get(handles.bathyFile, 'String');
            [~, ~, ext] = fileparts(file);
            
            % If file exists and is a mat-file, then continue
            if exist(file, 'file') && strcmp(ext, '.mat')
            
                set(handles.bathyLoadStatus, 'String', 'Loading. Please wait...');
                set(handles.startButton, 'Enable', 'off');
                drawnow nocallbacks
            %     drawnow expose update
            
                % Load mat-file
                handles.estFunc = load(file);
            
                % If correct variable exists
                if isfield(handles.estFunc, 'F')
            
                    % Force extrapolation method to be none
                    handles.estFunc.F.ExtrapolationMethod = 'none';
            
                    set(handles.bathyLoadStatus, 'String', 'File loaded!');
                    set(handles.bathyLoadStatus, 'BackgroundColor', [0; 1; 0]);
                else
                    handles = rmfield(handles, 'estFunc');
                    set(handles.bathyLoadStatus, 'Incorrect data in MAT-file');
                    set(handles.bathyLoadStatus, 'BackgroundColor', [1; 0; 0]);
                end
                set(handles.startButton, 'Enable', 'on');
            
                % Update handles structure
                guidata(hObject, handles);
            
            else
                set(handles.bathyLoadStatus, 'String', 'Specific file is not correct.');
            end
        end

        % Value changed function: setManualPI
        function setManualPI_Callback(app, event)
            % --- Executes on button press in setManualPI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to setManualPI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Enable the ping interval settings if box is checked
            if get(hObject, 'Value')
                set(handles.manualPI, 'Enable', 'on')
                set(handles.manualPIButton, 'Enable', 'on')
                handles.setPITimer.StartDelay = str2double(handles.settings.ManPingTime)*60;
                start(handles.setPITimer)           % Start timer
            else
                set(handles.manualPI, 'Enable', 'off')
                set(handles.manualPIButton, 'Enable', 'off')
                stop(handles.setPITimer)           % Start timer
            end
        end

        % Value changed function: startButton
        function startButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to startButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of startButton
            
            warning on verbose
            warning('error', 'instrument:fread:unsuccessfulRead'); %#ok<*CTPCT>
            warning('error', 'instrument:fscanf:unsuccessfulRead');
            
            if get(hObject, 'Value')
            
                %% Read settings values
                handles = readSettings(app, handles);
            
                %% Create the various timer objects
            
                % Timer for receiving subscription data
                handles.subcriptionTimer = timer('ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.1, ...
                    'BusyMode', 'drop', ...
                    'Name', 'Receive data timer', ...
                    'TimerFcn', @(obj, eventdata) getData(app, handles.output));
            
                % Timer for manual ping interval
                handles.setPITimer = timer('ExecutionMode', 'singleShot', ...
                    'TimerFcn', @(obj, eventdata) stopManualPI(app, handles.output), ...
                    'Name', 'Manual Ping Interval Timer', ...
                    'StartDelay', str2double(handles.settings.ManPingTime)*60);
            
                %% Initialize variables on each Start button press
                handles.currTime = NaT;             % Holds current ping time
                handles.firstPing = 1;              % Flag to signify EAL starting
                handles.firstPingCount = 0;         % Init # of starting pings missed
                handles.emittingDeepPing = 0;       % Flag for deep ping txmission
                handles.restartCount = 0;           % Init # of restarts
                handles.currentlyRunning = 0;       % Tells if getData() is running
            
                % Initialize plotting variables
                handles.lastDepths = nan(100,1);
                handles.lastSlopes = nan(100,1);
                handles.lastDZHs = nan(100,1);
                handles.lastRoughnesses = nan(100,1);
            
                %% Change GUI properties
            
                set(handles.restartTest, 'String', '');     % Clear restart status
            
                % Disable false bottom file selection and load button while running
                set(handles.bathyFile, 'Enable', 'off');
                set(handles.bathyFileBrowse, 'Enable', 'off');
                set(handles.loadBathy, 'Enable', 'off');
            
                set(handles.setManualPI, 'Enable', 'on');   % Enable Set Ping Interval
            
                % If bathymetry estimator doesn't exist, then disable all false
                % bottom removal inputs.
                if ~isfield(handles, 'estFunc')
                    set(handles.bathyLoadStatus, 'String', 'No bathymetry data loaded!');
                    set(handles.bathyLoadStatus, 'Enable', 'off');
                end
            
                % Change Start button and disable inputs
                set(handles.startButton, 'BackgroundColor', 'r')	% Make button red
                set(handles.startButton, 'String', 'Stop')          % Change to Stop
            
                % Disable K-Sync inputs while operating
                set(handles.checkboxKSync, 'Enable', 'Off')
                        
                % Make all output parameters blank
                set(handles.Depth, 'String', '')
                set(handles.pInterval, 'String', '')
                set(handles.lRange, 'String', '')
            
                % Update GUI display
                drawnow nocallbacks
            %     drawnow expose update
            
                %% Try connecting to ER60/EK80
                try
                    handles = connect2ER60(app, handles);
                catch ME
                    set(handles.restartTest, 'String', ME.message);
                    enableInputs(app, handles);
                    return
                end
            
                %% If using K-Sync, create udp object for sending depth outputs
                if get(handles.checkboxKSync, 'Value')
                    handles.KSyncDepth = udpport("byte", "IPV4");
                end
            
                %% If External Depth sensor info provided, create tcpip object
            
                % If External Depth sensor parameters are defined in the Settings.txt
                % file, create a TCP/IP object
                if ~isempty(handles.settings.ExtDepthIP)
                    if strcmp(handles.settings.ExtDepthType, 'IP')
                        handles.ExtDepth = tcpclient(handles.settings.ExtDepthIP, str2double(handles.settings.ExtDepthPort));
                    elseif strcmp(handles.settings.ExtDepthType, 'UDP')
                        handles.ExtDepth = udpport("datagram", "IPV4", 'LocalPort', str2double(handles.settings.ExtDepthPort));
%                         handles.ExtDepth.RemotePort = str2double(handles.settings.ExtDepthPort);
                        % configureMulticast(handles.ExtDepth, handles.settings.ExtDepthIP);
                    end
                end
                        
                %% Create file for storing noise floor measurements
            
                % If Noise Measurement directory doesn't exist, create it
                if ~isfolder(fullfile('C:\ProgramData\EAL', 'Noise Measurements'))
                    mkdir(fullfile('C:\ProgramData\EAL', 'Noise Measurements'))
                end
            
                % Create noise file
                handles.noiseFile = fopen(fullfile('C:\ProgramData\EAL', 'Noise Measurements', ...
                    sprintf('passiveNoise_D%s.txt', char(datetime('now', Format='yyyyMMdd''T''HHmmss')))), 'w');
            
                % Append header to file
                str = '';
                for i = 1:length(handles.freqs)
                    str = sprintf('%s,%dkHz(dB)', str, handles.freqs(i)/1e3);
                end
                fprintf(handles.noiseFile, 'Date,Time%s\n', str);
            
                %% Initialize plotting variables for rolling calculations
                handles.rollingX = cell(length(handles.freqs), 3);
                handles.rollingY = cell(length(handles.freqs), 3);
                handles.rollingZ = cell(length(handles.freqs), 3);
            
                %% Change display range
            
                % Set the logging range to the ranges defined in Settings.txt
                handles.normRange = nan(length(handles.freqs), 1);
                for i = 1:length(handles.freqs)
                    handles.normRange(i) = str2double(eval(sprintf('handles.settings.MaxLogRange%d', handles.freqs(i)/1e3)));
                end
            
                % Set the variable that will hold the current logging range
                handles.currRange = handles.normRange;
            
                % Put that range in the GUI
                set(handles.lRange, 'String', 'NA')
            
                % Initialize the first detection window using the full logging range.
                % Because there can be multiple logging ranges, use the maximum range
                % for the window.
                handles.RangeWindow = [0; max(handles.normRange)];
            
                % Change echogram display range
                for i = 1:length(handles.freqs)
                    rString1 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/RangeStart</paramName><paramValue>0</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    rString2 = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><SetParameter><paramName>WindowManager/ModeControl/' handles.ER60transceiverID{i} '_ES/UpperEchogram/Range</paramName><paramValue>' int2str(handles.normRange(i)) '</paramValue><paramType>5</paramType></SetParameter></method></request>' char(0)];
                    handles = sendrequest(app, handles, rString1);
                    handles = sendrequest(app, handles, rString2);
                end
            
                %% Get ER60 parameters
            
                % Get sound speed for calculations
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{1} '/SoundVelocity</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                handles.c = str2double(readbetween(app, '<value dt="5">','</value>',response));   % in ms
            
                % Get frequency dependent parameters that will be used for processing
                for i = 1:length(handles.freqs)
            
                    % Alongship sensitivity (degrees)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AngleSensitivityAlongship</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.AlongshipAngleSensitivity(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Athwartship sensitivity (degrees)
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AngleSensitivityAthwartship</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.AthwartshipAngleSensitivity(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Pulse length
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/PulseLength</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.tau(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Absorption
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/AbsorptionCoefficient</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.alpha(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Transmit Power
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/TransmitPower</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.Pt(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % On-axis Gain
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/Gain</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.G0(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % Equivalent Two-Way Beam Angle
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/EquivalentBeamAngle</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.psi(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
            
                    % S_A Correction
                    rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>TransceiverMgr/' handles.ER60transceiverID{i} '/SaCorrection</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    [handles, response] = sendrequest(app, handles, rString);
                    handles.Sa_corr(i) = ...
                        str2double(readbetween(app, '<value dt="5">','</value>',response));
                end
                        
                %% Store ER60 settings
            
                % Calculate ping interval based on logging range
                procTime = str2double(handles.settings.ProcBuf);
                handles.normRate = round(100*(procTime + (max(handles.normRange) * 2/handles.c)))/100;
            
                % Put those values in the GUI
                set(handles.pInterval, 'String', num2str(handles.normRate))
                set(handles.manualPI, 'String', num2str(handles.normRate))
            
                % See if ER60 was recording
                rString = ['<request><clientInfo><cid>' handles.ER60CLIENTID '</cid><rid>' int2str(handles.ER60RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>SounderStorageManager/SaveRawData</paramName><time>0</time></GetParameter></method></request>' char(0)];
                [handles, response] = sendrequest(app, handles, rString);
                handles.isRec = str2double(readbetween(app, '<value dt="3">','</value>',response));
            
                %% Collect passive noise measurements, if wanted
            
                % Make passive noise measurements if checkbox is selected
                if get(handles.checkboxNoise, 'Value')
            
                    % If noise timer exists, check to see if it's time for a new
                    % measurements
                    if isfield(handles, 'noiseTimer')
            
                        % If time for a new measurements, do it
                        if toc(handles.noiseTimer) > 60*str2double(handles.settings.PassiveInt)
                            handles = collectNoise(app, handles);
            
                        % Otherwise, display when the next measurement occurs
                        else
                            fprintf('Next noise measurements in %.0f seconds\n', 60*str2double(handles.settings.PassiveInt) - toc(handles.noiseTimer))
                        end
            
                    % If the timer doesn't exist, then collect noise data
                    else
                        handles = collectNoise(app, handles);
                    end
            
                % If user doesn't wish to collect noise measurements, set to NaN
                else
                    handles.noiseFloor = nan(1, length(handles.freqs));
                end
            
                %% Subscribe to data for all ER60 frequencies
                try
                    handles = subscribe2ER60(app, handles);
                catch ME
                    set(handles.restartTest, 'String', ME.message);
                    enableInputs(app, handles);
                    return
                end
            
                %% Create plots
                handles.h(1) = plot(handles.axesDepth, handles.lastDepths, 'LineWidth', 3);
                set(handles.axesDepth, 'YDir', 'reverse', 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesDepth, 'Depth')
            
                handles.h(2) = plot(handles.axesSlope, handles.lastSlopes, 'LineWidth', 3);
                set(handles.axesSlope, 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesSlope, 'Slope')
            
                handles.h(3) = plot(handles.axesDZH, handles.lastDZHs, 'LineWidth', 3);
                set(handles.axesDZH, 'XGrid', 'on', 'YGrid', 'on', 'XTickLabel', '')
                ylabel(handles.axesDZH, 'h_{ADZ}')
            
                handles.h(4) = plot(handles.axesRoughness, handles.lastRoughnesses, 'LineWidth', 3);
                set(handles.axesRoughness, 'XGrid', 'on', 'YGrid', 'on')
                ylabel(handles.axesRoughness, 'Roughness')
            
                %% Update handles and start receiving data
            
                % The handles structure must be updated first so that when the
                % subscription timer is started, it is accessing the most up-to-date
                % version of handles.
                guidata(handles.output, handles)	% Update GUI handles structure
                start(handles.subcriptionTimer);           % Start reading ER60 udp packets
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'EAL';
            app.figure1.Tag = 'figure1';
            app.axesDepth = uiaxes(app.figure1);
            app.axesDepth.Tag = 'axesDepth';
            app.axesSlope = uiaxes(app.figure1);
            app.axesSlope.Tag = 'axesSlope';
            app.axesRoughness = uiaxes(app.figure1);
            app.axesRoughness.Tag = 'axesRoughness';
            app.axesDZH = uiaxes(app.figure1);
            app.axesDZH.Tag = 'axesDZH';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Input';
            app.uipanel1.Tag = 'uipanel1';
            app.editDispRange = uicheckbox(app.uipanel1);
            app.editDispRange.Tag = 'editDispRange';
            app.editDispRange.Text = 'Unlock display range?';
            app.setManualPI = uicheckbox(app.uipanel1);
            app.setManualPI.ValueChangedFcn = createCallbackFcn(app, @setManualPI_Callback, true);
            app.setManualPI.Tag = 'setManualPI';
            app.setManualPI.Enable = 'off';
            app.setManualPI.Text = 'Set Ping Interval';
            app.manualPI = uieditfield(app.uipanel1, 'text');
            app.manualPI.Tag = 'manualPI';
            app.manualPI.Enable = 'off';
            app.manualPIButton = uibutton(app.uipanel1, 'push');
            app.manualPIButton.Tag = 'manualPIButton';
            app.manualPIButton.Enable = 'off';
            app.manualPIButton.Text = 'Set';
            app.checkboxCheckDeepBottom = uicheckbox(app.uipanel1);
            app.checkboxCheckDeepBottom.ValueChangedFcn = createCallbackFcn(app, @checkboxCheckDeepBottom_Callback, true);
            app.checkboxCheckDeepBottom.Tag = 'checkboxCheckDeepBottom';
            app.checkboxCheckDeepBottom.Text = 'Check Deep Bottom?';
            app.buttonCheckBottomNow = uibutton(app.uipanel1, 'state');
            app.buttonCheckBottomNow.Tag = 'buttonCheckBottomNow';
            app.buttonCheckBottomNow.Text = 'Check now';
            app.text48 = uilabel(app.uipanel1);
            app.text48.Tag = 'text48';
            app.text48.Text = 'Next detection:';
            app.nextBottomCheck = uieditfield(app.uipanel1, 'text');
            app.nextBottomCheck.Tag = 'nextBottomCheck';
            app.nextBottomCheck.Enable = 'off';
            app.fixFB = uicheckbox(app.uipanel1);
            app.fixFB.ValueChangedFcn = createCallbackFcn(app, @fixFB_Callback, true);
            app.fixFB.Tag = 'fixFB';
            app.fixFB.Text = 'Correct false bottom?';
            app.fixFB.Value = true;
            app.FBCorrected = uieditfield(app.uipanel1, 'text');
            app.FBCorrected.Tag = 'FBCorrected';
            app.FBCorrected.Enable = 'off';
            app.text38 = uilabel(app.uipanel1);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Bathymetry file:';
            app.bathyFile = uieditfield(app.uipanel1, 'text');
            app.bathyFile.Tag = 'bathyFile';
            app.bathyFileBrowse = uibutton(app.uipanel1, 'push');
            app.bathyFileBrowse.ButtonPushedFcn = createCallbackFcn(app, @bathyFileBrowse_Callback, true);
            app.bathyFileBrowse.Tag = 'bathyFileBrowse';
            app.bathyFileBrowse.Text = '...';
            app.loadBathy = uibutton(app.uipanel1, 'push');
            app.loadBathy.ButtonPushedFcn = createCallbackFcn(app, @loadBathy_Callback, true);
            app.loadBathy.Tag = 'loadBathy';
            app.loadBathy.Text = 'Load';
            app.bathyLoadStatus = uieditfield(app.uipanel1, 'text');
            app.bathyLoadStatus.Tag = 'bathyLoadStatus';
            app.bathyLoadStatus.Enable = 'off';
            app.bathyLoadStatus.Value = 'No bathymetry data loaded!';
            app.checkboxNoise = uicheckbox(app.uipanel1);
            app.checkboxNoise.Tag = 'checkboxNoise';
            app.checkboxNoise.Text = 'Measure noise?';
            app.checkboxKSync = uicheckbox(app.uipanel1);
            app.checkboxKSync.Tag = 'checkboxKSync';
            app.checkboxKSync.Text = 'K-Sync?';
            app.checkboxKSync.Value = true;
            app.checkboxHake = uicheckbox(app.uipanel1);
            app.checkboxHake.Tag = 'checkboxHake';
            app.checkboxHake.Text = 'Hake?';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Output';
            app.uipanel2.Tag = 'uipanel2';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Depth (m):';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Ping Interval (s):';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Logging Depth (m):';
            app.pInterval = uieditfield(app.uipanel2, 'text');
            app.pInterval.Tag = 'pInterval';
            app.pInterval.Enable = 'off';
            app.lRange = uieditfield(app.uipanel2, 'text');
            app.lRange.Tag = 'lRange';
            app.lRange.Enable = 'off';
            app.Depth = uieditfield(app.uipanel2, 'text');
            app.Depth.Tag = 'Depth';
            app.Depth.Enable = 'off';
            app.text42 = uilabel(app.uipanel2);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Ping Time:';
            app.pingTime = uieditfield(app.uipanel2, 'text');
            app.pingTime.Tag = 'pingTime';
            app.pingTime.Enable = 'off';
            app.startButton = uibutton(app.figure1, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButton_Callback, true);
            app.startButton.Tag = 'startButton';
            app.startButton.Text = 'START';
            app.restartTest = uieditfield(app.figure1, 'text');
            app.restartTest.Tag = 'restartTest';
            app.buttonSettings = uibutton(app.figure1, 'push');
            app.buttonSettings.ButtonPushedFcn = createCallbackFcn(app, @buttonSettings_Callback, true);
            app.buttonSettings.Tag = 'buttonSettings';
            app.buttonSettings.Text = 'Open Settings';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EAL_IWCPS(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)EAL_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end