classdef estimateDepth_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IPAddressEditField       matlab.ui.control.EditField
        IPAddressEditFieldLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        TextAreaLabel            matlab.ui.control.Label
        DepthEditField           matlab.ui.control.NumericEditField
        DepthEditFieldLabel      matlab.ui.control.Label
        Button                   matlab.ui.control.StateButton
    end


    properties (Access = private)
        TimerObj        % Timer object for estimate depth
        EK80RequestID   % Description
        EK80 % Description
        EK80CLIENTID % Description
        EK80CLIENTSEQNO % Description
        aliveTimer % Description
    end

    methods (Access = private)

        function depthTimerFcn(app)

            % drawnow nocallbacks

            try

                % If start button is still pressed
                if app.Button.Value

                    flushinput(app.EK80)
                    disp('Getting location...')
                    fprintf('Client Seq. No = %d\n', app.EK80CLIENTSEQNO)

                    % Get longitude
                    rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OwnShip/Longitude</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    response = sendrequest(app, rString);
                    longitude = str2double(readbetween(app, '<value dt="5">','</value>',response));
fprintf('Client Seq. No = %d\n', app.EK80CLIENTSEQNO)
                    % Get latitude
                    rString = ['<request><clientInfo><cid>' app.EK80CLIENTID '</cid><rid>' int2str(app.EK80RequestID) '</rid></clientInfo><type>invokeMethod</type><targetComponent>ParameterServer</targetComponent><method><GetParameter><paramName>OwnShip/Latitude</paramName><time>0</time></GetParameter></method></request>' char(0)];
                    response = sendrequest(app, rString);
                    latitude = str2double(readbetween(app, '<value dt="5">','</value>',response));

                    disp('Estimate depth...')

                    % Get depth at that position
                    depth = -getDepth(app, latitude, longitude);

                    % disp(depth)
                    app.DepthEditField.Value = depth;
                    fprintf('Depth = %.2f\n', depth)

                    % Write results to a text file if none of them are NaN
                    if all(~isnan([longitude, latitude, depth]))
                        fid = fopen('currDepth.txt', 'w');
                        fprintf(fid, '%s,%f,%f,%f', datetime('now'), latitude, longitude, depth);
                        fclose(fid);
                    end

                    app.TextArea.Value = sprintf('%s',datetime('now'));

                    % Update GUI with depth
                    app.DepthEditField.Value = depth;
                    % set(app.depthBox, 'Value', fprintf('%.2f', depth))
                    % drawnow nocallbacks

                    fprintf('Exiting timer function')

                % Otherwise, if stop button was pressed, disconnect
                else

                    % Change button to display Start
                    set(app.Button, 'Value', 0, 'Text', 'START', 'BackgroundColor', 'g')

                    % Stop timer
                    stop(app.TimerOb);
                    stop(app.aliveTimer)

                    % Disconnect from EK80
                    fwrite(app.EK80, ['DIS' char(0) ...
                        'Name:Simrad;Password:' char(0)], 'char');

                    % Delete all instruments
                    clear(app.EK80)
                end

            % If an error occurred
            catch ME
                disp(getReport(ME))

                % Stop timer
                stop(app.TimerObj)    % Timer for obtaining depths
                stop(app.aliveTimer)

                % Delete all instruments
                clear(app.EK80)

                % Continually try to reconnect to EK80
                flag = 1;
                while flag

                    % Issue full drawnow command to flush event queue and process any
                    % figure changes
                    drawnow;

                    disp('Trying to reconnect...')

                    % Continually try to reconnect to the EK80 unless the
                    % stop button is pressed
                    if app.Button.Value
                        try
                            connect2EK80(app);
                            flag = 0;
                            disp('Reconnected!')

                            % Start timers
                            start(app.TimerObj);
                            start(app.aliveTimer)

                        catch ME
                            disp(getReport(ME))
                        end
                    else
                        disp('Reconnection aborted.')
                        flag = 0;

                        % Change button to display Start
                        set(app.Button, 'Value', 'Start', 'BackgroundColor', 'g')
                        drawnow nocallbacks
                    end
                end
            end
        end

        function sendAliveMessage(app)
            
            fprintf('Sending alive message at %s\n', datetime("now"))
            fprintf('Client Seq. No = %d\n', app.EK80CLIENTSEQNO)

            % Send alive message to ER60 object
            fwrite(app.EK80, ['ALI' char(0) ...
                'ClientID:' app.EK80CLIENTID ...
                ',SeqNo:' int2str(app.EK80CLIENTSEQNO) ...
                char(0)], 'char');
            % app.EK80CLIENTSEQNO = app.EK80CLIENTSEQNO+1;

            disp('Message sent!')
        end

        function connect2EK80(app)

            try

                %% Get/set EK80 connection settings
                app.EK80RequestID = 1;  % Init. EK80 request ID

                %% Request server info and open connection

                % Create UDP port object
                app.EK80 = udpport("byte", "IPV4");

                % Send request server info
                write(app.EK80, ['RSI' char(0)], "char", ...
                    app.IPAddressEditField.Value, 37655);

                % Read header. If nothing is sent back, then a connection setting must
                % be wrong.
                try
                    header = read(app.EK80,4,'char');	% Read header
                    if ~contains(header, 'SI2')
                        error('Incorrect response.  Check IP address')
                    end
                catch ME
                    error('No response from EK80.  Check settings')
                end

                % Get remote commandport which should be used to set up subscriptions and
                % continuously receive and respond alive messages for this connection.
                read(app.EK80,64,'char');                   % Application Type
                read(app.EK80,64,'char');                   % Application name
                read(app.EK80,128,'char');                  % Application description
                read(app.EK80,1,'int32');                   % Application ID
                commandPort = read(app.EK80,1,'int32');	    % Command Port
                read(app.EK80,1,'int32');                   % Mode
                read(app.EK80,64,'char');                   % Host name

                %% Connect to EK80 server

                % Try to connect with a user and password which must be defined in
                % server EK80 application (Users and Passwords dialogue).
                write(app.EK80, ['CON' char(0) 'Name:Simrad;Password:' char(0)], 'char', ...
                    app.IPAddressEditField.Value, commandPort);

                % Receive response
                header = read(app.EK80,4,'char');
                if contains(header, 'RES')
                    read(app.EK80,4,'char');
                    read(app.EK80,22,'char');
                    response = read(app.EK80,1400,'char');
                else
                    error('Unknown response: %s', header);
                end

                % Get CLIENTID
                app.EK80CLIENTID = regexp(response, 'ClientID:(\d+),', 'tokens');
                app.EK80CLIENTID = app.EK80CLIENTID{:}{:};

                % Initialiaze client sequence number
                app.EK80CLIENTSEQNO = 1;

            catch ME

                % Delete udp object to EK80 server
                if isfield(app, 'EK80')
                    if isvalid(app.EK80); clear(app.EK80); end
                end

                error(ME.message);
            end

        end

        function response = sendrequest(app, str)

            % Send request
            header = ['REQ' char(0)];                           % Header
            temp = [int2str(app.EK80CLIENTSEQNO) ',1,1'];   % Sequence number
            msgcontrol = [temp repmat(char(0), 1, 22-length(temp))];    % Msg control
            s = [header msgcontrol str];                        % Put it all together

            % Send command
            flushinput(app.EK80)
            write(app.EK80, s, 'char');                     % Send to EK80

            temp = nan(1,3);
            flag = 1;
            while flag
                temp = [temp(2:3) double(read(app.EK80, 1, 'int8'))];

                if isequal(temp, double('RES'))
                    flag = 0;
                elseif isequal(temp, double('RTR'))
                    write(app.EK80, s, 'char');
                end
            end

            % Received request response
            read(app.EK80, 27, 'char');
            response = read(app.EK80,1400,"char");

            app.EK80CLIENTSEQNO = app.EK80CLIENTSEQNO+1;
        end

        function str = readbetween(~, FirstPattern, LastPattern, Text)
            % Extracts the string located between FirstPattern and LastPattern in the
            % string Text

            pat = [FirstPattern '(.*)' LastPattern];
            temp = regexp(Text, pat, 'tokens');
            str = temp{:}{:};
        end

        function depth = getDepth(~, latitude, longitude)
            %GETDEPTH Estimate seabed depth at specific location.
            %   DEPTH = GETDEPTH(LATITUDE, LONGITUDE) estimates the seabed depth DEPTH
            %   at specified coordinate(s). LATITUDE and LONGITUDE coordinates can
            %   either be numeric arrays specifying decimal degrees or cell arrays of
            %   strings specifying degrees, minutes, and seconds or GPS degrees. DEPTH
            %   is returned in meters, where negative values correspond to depths below
            %   sea level.
            %
            %   If LATITUDE and LONGITUDE are cell arrays of strings, the hemisphere
            %   designator (N/S/E/W) must prepend the location (e.g. 'N 37 23 30') and
            %   all values must be separated by spaces.
            %
            %   This function uses the Marine Geoscience Data System website to obtain
            %   seabed estimates from their Global Multi-Resolution Topography (GMRT)
            %   dataset. Thus, an Internet connection is required.
            %
            %   Examples:
            %       getDepth(32.930667, -117.3175)
            %       getDepth('N 32 55 50', 'W 117 19 3')
            %       getDepth('N 32 55.84', 'W 117 19.05')
            %       getDepth([33.52 35.14], [-119.88 -123.35])
            %       getDepth({'N 33 31 12' 'N 35 8 24'}, {'W 119 52 48' 'W 123 21 0'})
            %       getDepth({'N 33 31.2' 'N 35 8.4'}, {'W 119 52.8' 'W 123 21.0'})
            %
            %   Reference:
            %       Ryan, W.B.F., S.M. Carbotte, J.O. Coplan, S. O'Hara, A. Melkonian,
            %       R. Arko, R.A. Weissel, V. Ferrini, A. Goodwillie, F. Nitsche, J.
            %       Bonczkowski, and R. Zemsky (2009), Global Multi-Resolution
            %       Topography synthesis, Geochem. Geophys. Geosyst., 10, Q03014, doi:
            %       10.1029/2008GC002332

            % Created by Josiah Renfree, May 16, 2016
            % Advanced Survey Technologies / Southwest Fisheries Science Center
            % National Oceanic and Atmospheric Administration

            % Verify that two inputs were given
            if nargin ~= 3
                error('Function requires two input arguments giving the GPS coordinates.')

                % Verify that both inputs are the same type (e.g. both numeric arrays)
            elseif ~strcmp(class(latitude), class(longitude))
                error('Both inputs must be of the same type, e.g. numeric arrays or cell arrays.')

                % If inputs are single strings (i.e. not cell array), convert to cell
            elseif ischar(latitude)
                latitude = {latitude};
                longitude = {longitude};

                % Verify that both inputs are 1-D arrays of the same length
            elseif (length(latitude) ~= length(longitude)) || min(size(latitude)) ~= 1
                error('Both inputs must be 1-D arrays of the same length.')
            end

            % If inputs are strings, convert to decimal degrees
            if ~isnumeric(latitude)

                % Creaty empty arrays to hold results
                decLat = nan(length(latitude), 1);
                decLon = nan(length(latitude), 1);

                % Cycle through each input
                for i = 1:length(latitude)

                    % Determine if input is in degrees/minutes/seconds or GPS format by
                    % looking for period
                    idx = strfind(latitude{i}, '.');

                    % Parse using spaces
                    tempLat = regexp(latitude{i}, ' ', 'split');
                    tempLon = regexp(longitude{i}, ' ', 'split');

                    % Verify that hemisphere designators are correct
                    if ~any(strcmp(tempLat{1}, {'N', 'S'})) || ...
                            ~any(strcmp(tempLon{1}, {'E', 'W'}))
                        error('Missing hemisphere designator for input given.')
                    end

                    % If no period found, it is in degrees/minute/seconds
                    if isempty(idx)

                        % Verify that both returned 4 results
                        if length(tempLat) ~= 4 || length(tempLon) ~= 4
                            error('Incorrect format. Please check inputs.')
                        end

                        % Convert to decimal degrees
                        decLat(i) = str2double(tempLat{2}) + ...
                            (str2double(tempLat{3}) + str2double(tempLat{4})/60) / 60;
                        decLon(i) = str2double(tempLon{2}) + ...
                            (str2double(tempLon{3}) + str2double(tempLon{4})/60) / 60;

                        % If one period found, it is GPS format
                    elseif isscalar(idx)

                        % Verify that both returned 3 results
                        if length(tempLat) ~= 3 || length(tempLon) ~= 3
                            error('Incorrect format. Please check inputs.')
                        end

                        % Convert to decimal degrees
                        decLat(i) = str2double(tempLat{2}) + str2double(tempLat{3})/60;
                        decLon(i) = str2double(tempLon{2}) + str2double(tempLon{3})/60;

                        % If more than one period found, throw error
                    else
                        error('Unknown format. Please check inputs.')
                    end

                    % If Southern hemisphere, make negative
                    if strcmpi(tempLat{1}, 's')
                        decLat(i) = -1 * decLat(i);
                    end

                    % If Western hemisphere, make negative
                    if strcmpi(tempLon{1}, 'w')
                        decLon(i) = -1 * decLon(i);
                    end
                end

                % Otherwise, if inputs are numeric arrays, store in new variables for
                % obtaining depth
            else
                decLat = latitude;
                decLon = longitude;
            end

            % Cycle through each location and obtain depth
            depth = nan(length(decLat), 1);
            for i = 1:length(decLat)

                url = sprintf(['https://www.gmrt.org/services/PointServer/?' ...
                    'latitude=%.5f&amp&longitude=%.5f&format=text%2Fplain'], ...
                    decLat(i), decLon(i));

                % Encompass urlread with try/catch block, in case Internet goes down
                % and can't retrieve a depth
                try

                    % Send URL and read resulting depth
                    depth(i) = str2double(webread(url));

                    % If an error occurred, set depth to NaN and carry on
                catch ME
                    depth(i) = NaN;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.TimerObj = timer('ExecutionMode', 'fixedSpacing','Period',5,'TimerFcn',@(~,~) depthTimerFcn(app));

            % Create timer for sending alive messages
            app.aliveTimer = timer('ExecutionMode', 'fixedRate','Period',1,'TimerFcn', @(~,~) sendAliveMessage(app));

        end

        % Value changed function: Button
        function StartButtonPushed(app, event)

            % If START pressed, connect to EK80 and start timer
            if app.Button.Value

                % Try to connect to EK80
                try
                    connect2EK80(app);
                    app.TextArea.Value = 'Connected to EK80!';
                    
                % If connection was unsuccessul, display message and change button
                catch ME
                    disp(ME.message)
                    
                    % Change button to display Start
                    set(app.Button, 'Value', 0, 'Text', 'Start', 'BackgroundColor', 'g')
                    return
                end

                set(app.Button, 'Text', 'STOP', 'BackgroundColor', 'r')

                % Start timers
                start(app.TimerObj)
                start(app.aliveTimer)

            % Otherwise stop timer and disconnect from EK80
            else

                % Change button to display Start
                set(app.Button, 'Text', 'Start', 'BackgroundColor', 'g')

                % Stop timer
                stop(app.TimerObj);
                stop(app.aliveTimer)

                % Disconnect from EK80
                fwrite(app.EK80, ['DIS' char(0) ...
                    'Name:Simrad;Password:' char(0)], 'char');

                % Clear EK80 udp obect
                delete(app.EK80)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Button.Text = 'START';
            app.DepthEditFieldLabel = uilabel(app.UIFigure);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthEditField.ValueDisplayFormat = '%11.0f';
            app.DepthEditField.Tag = 'depthBox';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.IPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressEditField.Value = '192.168.122.60';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estimateDepth_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end